classdef CollectMaterial < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Title           matlab.ui.control.Label
        Label_2         matlab.ui.control.Label
        CourseName      matlab.ui.control.DropDown
        Upload          matlab.ui.control.Button
        Label_3         matlab.ui.control.Label
        MaterialType    matlab.ui.control.DropDown
        Label_4         matlab.ui.control.Label
        ClassYear       matlab.ui.control.DropDown
        Username        matlab.ui.control.EditField
        UsernameLabel   matlab.ui.control.Label
        UITable         matlab.ui.control.Table
        HashButton      matlab.ui.control.Button
        FileListButton  matlab.ui.control.Button
        Label_5         matlab.ui.control.Label
        MaterialSpec    matlab.ui.control.DropDown
        CustomizedSpec  matlab.ui.control.EditField
        Label_6         matlab.ui.control.Label
        Info            matlab.ui.control.TextArea
        Developer       matlab.ui.control.Label
        Label_7         matlab.ui.control.Label
        CurrentYear     matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        DsCurriculum
        Curriculum
        FileList
        RepoPath = 'd:\Repo\'; % 备案材料存放根路径
        CoursePath
        Log = log4m.getLogger;
        FilePath % 年度备案材料文件提交列表路径
    end
    
    methods (Access = private)
        
        function UpdateFileList(app,rowNum)
            if exist('rowNum','var')
                app.FileList = cell(rowNum,6);
                app.FileList(:,1) = cellstr(app.Curriculum.Name);
            end
            app.FileList = GetFileList(app.FileList);
            app.Log.info('UpdateFileList','更新文件列表');
        end
        
        function ResetUITable(app)
            app.UITable.ColumnName = {'名称','课程教纲','考核材料',...
                '评价报告','上传用户','上传日期'};            
            listFileName = sprintf('FileList%s.xlsx',app.CurrentYear.Value);
            app.FilePath = fullfile(app.RepoPath,listFileName);
            if exist(app.FilePath,'file') ~= 2 % 生成相应的文件
                [~,prompt1] = ListCourses(app.CurrentYear.Value,app.RepoPath);
                app.Log.info('startupFcn',prompt1)
                prompt2 = sprintf('新建文件%s',app.FilePath);
                app.Log.info('startupFcn',prompt2)
            end
            app.FileList = readcell(app.FilePath);
            mask = cellfun(@(x) any(isa(x,'missing')),app.FileList);
            app.FileList(mask) = {[]};
            app.FileListButton.Enable = true; % 仅当已载入app.FileList后才可更新
            app.UITable.Data = app.FileList;
            app.MaterialType.Items = {'';'教学大纲';'考核材料';'评价报告'};
            app.MaterialType.Value = '';
            app.ClassYear.Items = {'';'2018';'2019';'2020';'2021';'2022'};
            app.ClassYear.Value = '';
            app.ClassYear.Editable = true;
            app.Upload.Enable = false;
            app.CourseName.Enable = false;
            app.ClassYear.Enable = false;
            app.MaterialType.Enable = false;
            app.HashButton.Visible = false;
            app.FileListButton.Visible = false;
            app.MaterialSpec.Enable = false;
            app.CustomizedSpec.Enable = false;
            app.Info.Editable = false;
            app.Info.Value = '请在“用户姓名”文本框中输入上传文件的教师姓名...';            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Log.setFilename('d:\Repo\logfile.txt');
            ResetUITable(app);
        end

        % Button pushed function: Upload
        function UploadButtonPushed(app, event)
            filter = {'*.*'};
            [file,path] = uigetfile(filter,'选取上传的单个文件','MultiSelect','off');
            if ~isequal(file,0)
                source = [path,file];
                [~,~,ext] = fileparts(source);
                fileHash = DataHash(source,'file');
                inf1 = sprintf('%s上传文件%s',app.Username.Value,source);
                app.Log.info('UploadButtonPushed',inf1);
                if app.MaterialSpec.Value ~= "其他"
                    newFilename = sprintf('《%s》（%s）%s：%s_%s',...
                        app.CourseName.Value,...
                        app.ClassYear.Value,...
                        app.MaterialType.Value,...
                        app.MaterialSpec.Value,...
                        fileHash(end-3:end));
                else
                    newFilename = sprintf('《%s》（%s）%s：%s_%s',...
                        app.CourseName.Value,...
                        app.ClassYear.Value,...
                        app.MaterialType.Value,...
                        app.CustomizedSpec.Value,...
                        fileHash(end-3:end));
                end
                destination = fullfile(app.CoursePath,...
                    app.MaterialType.Value,[newFilename,ext]);
                copyfile(source,destination)
                courseList = extractBetween(app.FileList(:,1),'《','》');
                idx = strcmp(app.CourseName.Value,courseList);
                switch app.MaterialType.Value
                    case('教学大纲')
                        col = 2;
                    case('考核材料')
                        col = 3;
                    case('评价报告')
                        col = 4;
                end
                if isempty(app.FileList{idx,col}) || ...
                    isequal(app.FileList{idx,col},'-')
                    app.FileList{idx,col} = fileHash(end-3:end);
                else
                    app.FileList(idx,col) = join([app.FileList(idx,col),...
                        {fileHash(end-3:end)}],', ');
                end
                app.FileList{idx,5} = app.Username.Value;
                app.FileList{idx,6} = datestr(now);
                app.UITable.Data(idx,[col,5,6]) = app.FileList(idx,[col,5,6]);
                inf2 = sprintf('%s拷贝文件%s',app.Username.Value,destination);
                app.Log.info('UploadButtonPushed',inf2)
                % 保存FileList
                mask = cellfun(@(x) any(isa(x,'missing')),app.FileList);
                app.FileList(mask) = {[]};
                writecell(app.FileList,app.FilePath);
                prompt = sprintf('保存%s',app.FilePath);
                app.Log.info('UploadButtonPushed',prompt)
                % 更新FileList
                app.Log.info('UploadButtonPushed','调用UpdateFileList()');
                UpdateFileList(app)
                app.UITable.Data = app.FileList;
            end
        end

        % Value changed function: CourseName
        function CourseNameValueChanged(app, event)
            value = app.CourseName.Value;
            if isempty(value)
                info = sprintf('%s设定课程名称为空值，当前课程路径为%s',...
                    app.Username.Value,app.RepoPath);
                app.CoursePath = app.RepoPath;
                app.MaterialType.Enable = false;
                app.MaterialSpec.Enable = false;
                app.CustomizedSpec.Enable = false;
                app.CustomizedSpec.Value = "";
            else
                app.CoursePath = [app.RepoPath,value,'\'];
                if exist(app.CoursePath,'dir') == 0
                    mkdir(app.CoursePath)
                    mkdir(app.CoursePath,'教学大纲\')
                    mkdir(app.CoursePath,'考核材料\')
                    mkdir(app.CoursePath,'评价报告\')
                    info = sprintf('%s创建目录%s',app.Username.Value,...
                        app.CoursePath);
                else
                    info = sprintf('%s设定当前目录%s',app.Username.Value,...
                        app.CoursePath);
                end
                app.MaterialType.Enable = true;
            end
            app.Log.info('CourseNameValueChanged',info)
            app.Info.Value = '请在“文件分类”下拉框中选择将上传文件的分类...';
            app.Upload.Enable = false;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.CourseName.Value)
                app.Log.info('UIFigureCloseRequest','关闭程序');
                if iscell(app.FileList)
                    mask = cellfun(@(x) any(isa(x,'missing')),app.FileList);
                    app.FileList(mask) = {[]};
                    writecell(app.FileList,app.FilePath);
                    prompt = sprintf('保存%s后关闭程序',app.FilePath);
                    app.Log.info('UIFigureCloseRequest',prompt);
                end
            end           
            delete(app)
        end

        % Value changed function: Username
        function UsernameValueChanged(app, event)
            value = app.Username.Value;
            adminList = {'关国强'};
            app.ClassYear.Enable = true;
            if any(strcmp(value,adminList))
                app.HashButton.Visible = true;
                app.FileListButton.Visible = true;
                info = sprintf('管理员用户设为%s',value);
            else
                app.HashButton.Visible = false;
                app.FileListButton.Visible = false;
                info = sprintf('用户设为%s',value);
            end
            app.Log.info('UsernameValueChanged',info)
            app.Info.Value = '请在“学生年级”下拉框中选择（或输入）将上传文件所属的学生年级...';
        end

        % Value changed function: ClassYear
        function ClassYearValueChanged(app, event)
            value = app.ClassYear.Value;
            switch value
                case({'2017','2018'})
                    curriculumYear = '2019';
                case({'2019','2020'})
                    curriculumYear = '2019';
                case({'2021','2022'})
                    curriculumYear = '2021';
                case({'2023','2024'})
                    curriculumYear = '2023';
                otherwise
                    error('输入学生年级有误！')
            end
            if isempty(value)
                app.Log.info('ReleaseYearValueChanged',...
                    sprintf('%s设定学生年级为空值',app.Username.Value));
                app.CourseName.Enable = false;
                app.MaterialType.Enable = false;
                app.MaterialSpec.Enable = false;
                app.FileListButton.Enable = false;
            else
                app.Log.info('ReleaseYearValueChanged',...
                    sprintf('%s设定学生年级为%s（培养方案年份为%s），载入相应的培养方案',...
                    app.Username.Value,value,curriculumYear));
                app.DsCurriculum = sprintf('db_Curriculum%s',curriculumYear);
                load('database.mat',app.DsCurriculum)
                app.Curriculum = eval(app.DsCurriculum);
                idx = strcmp([app.Curriculum.Remark],"提交备案");
                app.Curriculum = app.Curriculum(idx,:);
                app.CourseName.Items = ["";app.Curriculum.Name];
                app.CourseName.Value = "";
                app.CourseName.Enable = true;
                app.Info.Value = '请在“课程名称”下拉框中选择将上传文件所属的课程名称...';
            end
        end

        % Button pushed function: HashButton
        function HashButtonPushed(app, event)
            info = ChkFileHash(app.RepoPath);
            app.Log.info('HashButtonPushed',info);
            app.Log.info('HashButtonPushed','调用UpdateFileList()');
            UpdateFileList(app);
        end

        % Button pushed function: FileListButton
        function FileListButtonPushed(app, event)
            % 当已载入app.FileList后
            app.Log.info('FileListButtonPushed','调用UpdateFileList()');
            UpdateFileList(app);
            app.UITable.Data = app.FileList;
            app.Log.info('FileListButtonPushed',sprintf('更新文件%s',app.FilePath));
            writecell(app.FileList,app.FilePath);
        end

        % Value changed function: MaterialType
        function MaterialTypeValueChanged(app, event)
            value = app.MaterialType.Value;
            if isempty(value)
                app.MaterialSpec.Enable = false;
            else
                switch value
                    case("教学大纲")
                        items = ["","教学大纲","其他"];
                    case("考核材料")
                        items = ["","考试样卷、参考答案及评分标准","成绩单",...
                            "学生样本","审核材料","考核要求","其他"];
                    case("评价报告")
                        items = ["","课程质量评价报告","其他"];
                end
                app.MaterialSpec.Items = items;
                app.MaterialSpec.Value = "";
                app.MaterialSpec.Enable = true;
            end
            app.CustomizedSpec.Enable = false;
            app.CustomizedSpec.Value = "";
            app.Upload.Enable = false;
            app.Info.Value = '注意每次上传一个文件，请在“文件说明”下拉框中选择上传文件说明...';
        end

        % Value changed function: MaterialSpec
        function MaterialSpecValueChanged(app, event)
            value = app.MaterialSpec.Value;
            if isempty(value)
                app.Upload.Enable = false;
                app.CustomizedSpec.Enable = false;
                app.CustomizedSpec.Value = "";
            else
                if value == "其他"
                    app.Upload.Enable = false;
                    app.CustomizedSpec.Enable = true;
                    app.Info.Value = '注意每次上传一个文件，请在“自定义文本框”中输入上传文件的自定义说明内容...';
                else
                    app.Upload.Enable = true;
                    app.CustomizedSpec.Enable = false;
                    app.CustomizedSpec.Value = "";
                    app.Info.Value = '注意每次上传一个文件，请点击"上传”按钮完成进行文件上传...';
                end
            end
                
        end

        % Value changed function: CustomizedSpec
        function CustomizedSpecValueChanged(app, event)
            value = app.CustomizedSpec.Value;
            if isempty(value)
                app.Upload.Enable = false;
            else
                app.Upload.Enable = true;
                app.Info.Value = '注意每次上传一个文件，请点击"上传”按钮完成进行文件上传...';
            end
        end

        % Value changed function: CurrentYear
        function CurrentYearValueChanged(app, event)
            ResetUITable(app);
            app.Username.Value = ''; % 重置文本框"用户姓名"为空值，使用户重新输入
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = '能源化工专业“课程教学原始材料”收集程序';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '课程名称';
            app.CourseName = uidropdown(app.UIFigure);
            app.CourseName.Items = {''};
            app.CourseName.ValueChangedFcn = createCallbackFcn(app, @CourseNameValueChanged, true);
            app.CourseName.Value = '';
            app.Upload = uibutton(app.UIFigure, 'push');
            app.Upload.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.Upload.Text = '上传';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '文件分类';
            app.MaterialType = uidropdown(app.UIFigure);
            app.MaterialType.Items = {'教学大纲', '考核材料', '评价报告'};
            app.MaterialType.ValueChangedFcn = createCallbackFcn(app, @MaterialTypeValueChanged, true);
            app.MaterialType.Value = '教学大纲';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '学生年级';
            app.ClassYear = uidropdown(app.UIFigure);
            app.ClassYear.Items = {'2018', '2019', '2020', '2021', '2022'};
            app.ClassYear.ValueChangedFcn = createCallbackFcn(app, @ClassYearValueChanged, true);
            app.ClassYear.Value = '2021';
            app.Username = uieditfield(app.UIFigure, 'text');
            app.Username.ValueChangedFcn = createCallbackFcn(app, @UsernameValueChanged, true);
            app.UsernameLabel = uilabel(app.UIFigure);
            app.UsernameLabel.Text = '用户姓名';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'课程'; '教学大纲'; '考核材料'; '评价报告'; '提交用户'; '提交日期'};
            app.UITable.RowName = {};
            app.HashButton = uibutton(app.UIFigure, 'push');
            app.HashButton.ButtonPushedFcn = createCallbackFcn(app, @HashButtonPushed, true);
            app.HashButton.Text = '更新Hash';
            app.FileListButton = uibutton(app.UIFigure, 'push');
            app.FileListButton.ButtonPushedFcn = createCallbackFcn(app, @FileListButtonPushed, true);
            app.FileListButton.Text = '更新FileList';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '文件说明';
            app.MaterialSpec = uidropdown(app.UIFigure);
            app.MaterialSpec.Items = {};
            app.MaterialSpec.ValueChangedFcn = createCallbackFcn(app, @MaterialSpecValueChanged, true);
            app.MaterialSpec.Value = {};
            app.CustomizedSpec = uieditfield(app.UIFigure, 'text');
            app.CustomizedSpec.ValueChangedFcn = createCallbackFcn(app, @CustomizedSpecValueChanged, true);
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '操作提示';
            app.Info = uitextarea(app.UIFigure);
            app.Developer = uilabel(app.UIFigure);
            app.Developer.Text = 'Built by GGQ (gqguan@scut.edu.cn)';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '数据年度';
            app.CurrentYear = uidropdown(app.UIFigure);
            app.CurrentYear.Items = {'2022', '2023', '2024'};
            app.CurrentYear.ValueChangedFcn = createCallbackFcn(app, @CurrentYearValueChanged, true);
            app.CurrentYear.Value = '2023';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CollectMaterial

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end