classdef GoalAdmin_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainUI           matlab.ui.Figure
        Title            matlab.ui.control.Label
        Label_2          matlab.ui.control.Label
        Name             matlab.ui.control.DropDown
        ResultText       matlab.ui.control.TextArea
        Info             matlab.ui.control.TextArea
        Label_3          matlab.ui.control.Label
        Code             matlab.ui.control.EditField
        Label_4          matlab.ui.control.Label
        Category         matlab.ui.control.EditField
        Label_5          matlab.ui.control.Label
        CompulsoryOrNot  matlab.ui.control.EditField
        Label_6          matlab.ui.control.Label
        Credit           matlab.ui.control.EditField
        Label_7          matlab.ui.control.Label
        Hours            matlab.ui.control.EditField
        Label_8          matlab.ui.control.Label
        Semester         matlab.ui.control.EditField
        CalcGA           matlab.ui.control.Button
        Save             matlab.ui.control.Button
        Image            matlab.ui.control.Image
        Version          matlab.ui.control.Label
        TabGroup         matlab.ui.container.TabGroup
        Tab1a            matlab.ui.container.Tab
        ListObj          matlab.ui.control.Table
        Tab1b            matlab.ui.container.Tab
        UIAxes           matlab.ui.control.UIAxes
        Export           matlab.ui.control.Button
        Copyright        matlab.ui.control.Label
        TabGroup2        matlab.ui.container.TabGroup
        Tab2             matlab.ui.container.Tab
        GAList           matlab.ui.control.Table
        Tab2a            matlab.ui.container.Tab
        Obj2Way          matlab.ui.control.Table
        Tab2b            matlab.ui.container.Tab
        Weight           matlab.ui.control.Table
    end

    
    properties (Access = private)
        DsCurriculum = 'db_Curriculum2021'; % 课程体系描述字段
        DsIndicators = 'db_Indicators2021'; % 毕业要求指标点（内涵观测点）
        Curriculum;
        Indicators;
        DefaultSavePath = '';
        CurrentCourse Course
        Transcript Transcript
        O2W % 课程目标与评测方式的关系矩阵
        TranscriptLoaded; % 已载入成绩单
        GAResults % 达成度分析结果
        Logger log4m
    end
    
    properties (Access = public)
        AppInfo; % 日志记录
    end
    
    methods (Access = private)
        
        function docType = getType(app)
            switch app.Category.Value
                case('集中实践教学')
                    docType = '实习';
                otherwise
                    if ~isempty(regexp(app.CurrentCourse.Title,'实验','once'))
                        docType = '实验';
                    else
                        docType = '理论';
                    end                 
            end
        end
        
        function [results,w1,w2] = calcGA(app)
            % 获得成绩矩阵
            scores = app.Transcript.Detail{:,4:end};
            % 获得满分值
            fullValues = app.Transcript.SubPoints;
            % 获得关系矩阵
            o2w = app.Obj2Way.Data(:,2:end);
            % 获得权重矩阵
            weight = app.Weight.Data(:,2:end);
            % 检查变量尺寸
            if ~isequal(size(scores,2),length(fullValues),size(o2w,1))
                warning('成绩矩阵列数（%d）、分值向量（%d）和关系矩阵的行数（%d）不相等！',...
                    size(scores,2),length(fullValues),size(o2w,1));
                return
            end
            % 初始化输出变量
            results = zeros(size(o2w,2),1);
            w1 = weight;
            w2 = zeros(1,length(fullValues));
            % 计算各评测项的得分率
            x = mean(scores)./fullValues;
            % 计算各课程目标达成度
            for iObj = 1:size(o2w,2)
                idx1 = [o2w{:,iObj}]; % 与该课程目标相关的评测项
                firstLetterAll = categorical(cellfun(@(x){x(1)},cellstr(app.Transcript.VarNames)));
                firstLetterCat = categories(firstLetterAll);
                subPoint = zeros(length(firstLetterCat),1);
                for iWay = 1:length(firstLetterCat)
                    % 选定某评测方式中各项
                    idx2 = firstLetterAll==firstLetterCat{iWay};
                    idx = idx1 & idx2;
                    if any(idx)
                        w = fullValues(idx)/sum(fullValues(idx));
                        subPoint(iWay) = sum(w.*x(idx));
                        w2(idx) = w*weight{iWay,iObj}/sum(cell2mat(weight(:,iObj)),'omitnan'); % 评测项的权重
                    else
                        subPoint(iWay) = nan;
                    end
                end
                idxNaN = isnan(subPoint);
                subWeight = cell2mat(weight(:,iObj));
                subWeight(idxNaN) = nan;
                subWeight = subWeight/sum(subWeight,'omitnan');
                w1(:,iObj) = num2cell(subWeight); % 评测方式的权重矩阵
                results(iObj) = sum(subWeight.*subPoint,'omitnan'); % 课程目标达成度向量
            end
        end
        
        function [idx,prompt] = updateCourseInfo(app)
            if isempty(app.Name.Value)
                prompt = '课程选择为空！';
                return
            end
            idx = strcmp(app.Name.Value,{app.GAResults.Info});
            if sum(idx) ~= 1
                prompt = sprintf('记录中包含%d条数据（%s），载入最新的结果!',sum(idx),app.Name.Value);
                idx = find(idx,1,'last');
            else
                prompt = sprintf('获取课程信息(%s)！',app.Name.Value);
            end
            saveData = app.GAResults(idx);
            % 更新属性
            app.CurrentCourse = saveData.Data.cc;
            % 更新控件值
            app.Code.Value = app.CurrentCourse.Code;
            app.Category.Value = app.CurrentCourse.Category;
            if app.Category.Value == "选修课"
                app.CompulsoryOrNot.Value = "选修";
            else
                app.CompulsoryOrNot.Value = "必修";
            end
            app.Credit.Value = app.CurrentCourse.Credits;
            app.Hours.Value = app.CurrentCourse.ClassHour;
            app.Semester.Value = app.CurrentCourse.Semester;           
        end
        
        function prompt = DrawFig(app)
            currentCourse = app.Transcript.Name;
            currentClass = app.Transcript.Class;
            % 提取当前数据
            GAResult.xlabs = app.ListObj.Data(:,1);
            GAResult.y = cell2mat(app.ListObj.Data(:,end));
            GAResult.Name = sprintf('%s',app.Transcript.Class);
            % 提取历史数据
            pathFile = strcat(app.DefaultSavePath,'app1.mat');
            if exist(pathFile,'file') == 2
                load(pathFile,'savedData')
                idxCourse = strcmp(currentCourse,[savedData.Course]);
                % 提取存盘数据中的指定课程（按课程名称筛选）
                extractCourses = savedData(idxCourse);
                % 排除当前年级之后的数据
                classList4GivenCourse = {extractCourses.Class};
                delIdx = cellfun(@(x)str2double(x),classList4GivenCourse) >= str2double(currentClass);
                classList4GivenCourse(delIdx) = [];
                catList = categories(categorical(classList4GivenCourse));
                iCourses = cellfun(@(x)find(strcmp(x,{extractCourses.Class}),1,'last'),catList);
                if ~isempty(iCourses) || isa(iCourses,'numeric')
                    extractData = extractCourses(iCourses);
%                     extractData = savedData(iCourses);
                    GAResult.Name = [GAResult.Name,{extractData.Class}];
                    for i = 1:length(extractData)
                        GAResult.y = [GAResult.y,cell2mat(extractData(i).Data.listObj.Data(:,3))];
                    end
                end
            else
                app.Info.Value = SetLog(app,sprintf('服务端%s文件不存在！',pathFile));
            end
            % 绘制课程目标达成度柱状图
            bar(app.UIAxes,GAResult.y);
            xticklabels(app.UIAxes,GAResult.xlabs);
            title(app.UIAxes,sprintf('《%s》课程目标达成度',app.Name.Value));
            legend(app.UIAxes,GAResult.Name,"Location","eastoutside");
            figFile = [app.DefaultSavePath,'GAResult.png'];
            if isempty(app.Tab2.Parent)
                app.Tab2.Parent = app.TabGroup;
            end
            exportgraphics(app.UIAxes,figFile)
            prompt = sprintf('保存课程目标达成度柱状图于服务端%s',figFile);
        end
        
        function saveData = SaveData(app)
            % UI控件数据
            % 注意app designer中的对象不能直接保存，例如obj2Way = app.Obj2Way;
            propsList = {'Data','ColumnName','ColumnFormat','ColumnWidth','ColumnEditable'};
            obj2Way = getAppTabProps(app.Obj2Way,propsList);
            weight = getAppTabProps(app.Weight,propsList);
            listObj.Data = app.ListObj.Data;
            text = app.ResultText.Value;
            % UI内部变量
            cc = app.CurrentCourse;
            tr = app.Transcript;
            % 创建存盘数据结构
            saveData.Info = sprintf('%s（%s）：%s',cc.Title,tr.Class,datestr(now));
            saveData.Course = cc.Title;
            saveData.Class = tr.Class;
            saveData.Datetime = datetime(now,"ConvertFrom","datenum");
            savingVarList = {'obj2Way','weight','listObj','cc','tr','text'};
            for iVar = 1:length(savingVarList)
                saveData.Data.(savingVarList{iVar}) = eval(savingVarList{iVar});
            end
            
            function props = getAppTabProps(tabObj,propsList)
                % 输入参数检查
                %
                props = struct;
                for i = 1:length(propsList)
                    props.(propsList{i}) = get(tabObj,propsList{i});
                end
            end
            
        end
        
        function InitWeight(app)
            % 获得满分值
            fullValues = app.Transcript.SubPoints;
            % 获得关系矩阵
            o2w = cell2mat(app.Obj2Way.Data(:,2:end));
            % 激活显示评测方式权重表的标签
            app.Tab2b.Parent = app.TabGroup2;
            % 计算评测方式权重值
            firstLetterAll = categorical(cellfun(@(x){x(1)},cellstr(app.Transcript.VarNames)));
            firstLetterCat = categories(firstLetterAll);
            w1 = zeros(length(firstLetterCat),size(o2w,2));
            for iObj = 1:size(o2w,2)
                idx1 = o2w(:,iObj);
                w0 = fullValues/sum(fullValues(idx1));
                for iWay = 1:length(firstLetterCat)
                    % 选定某评测方式中各项
                    idx2 = firstLetterAll==firstLetterCat{iWay};
                    idx = idx1 & idx2';
                    if any(idx)
                        w1(iWay,iObj) = sum(w0(idx));
                    else
                        w1(iWay,iObj) = nan;
                    end
                end
            end
            % 构造表数据
            colNum = size(app.ListObj.Data,1)+1;
            rowNum = length(app.Transcript.Definition);
            dat3 = cell(rowNum,colNum);
            for iRow = 1:rowNum
                dat3{iRow,1} = char(app.Transcript.Definition(iRow).Name);
            end
            dat3(:,2:end) = num2cell(w1);
            % 设置表参数
            colNames = cell(1,colNum);
            colWidths = cell(1,colNum);
            colFormats = cell(1,colNum);
            colEditable = true(1,colNum);
            colNames{1} = '评测方式';
            colWidths{1} = 'auto'; 
            colFormats{1} = 'char';            
            for iCol = 2:length(colNames)
                colNames{iCol} = sprintf('[o%d]',iCol-1);
                colWidths{iCol} = 60;
                colFormats{iCol} = 'numeric';
            end
            app.Weight.ColumnName = colNames;
            app.Weight.ColumnWidth = colWidths;
            app.Weight.ColumnEditable = colEditable;
            % 填入表数据
            app.Weight.Data = dat3;
        end
        
        function prompt = updateWeight(app,data)
            app.Weight.Data(:,2:end) = data;
            prompt = sprintf('更新评测方式权重列表！');
        end
    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            homePath = cd;
            app.Logger = log4m.getLogger;
            app.Logger.info('GoalAdmin_main.mlapp','启动程序')
            if contains(homePath,'\Git\EnergyChE')
                app.DefaultSavePath = [homePath,'\'];
                app.Logger.info('startupFcn','本地调试模式')
            else
                idx = regexp(homePath,'\');
                app.DefaultSavePath = homePath(1:idx(end-3));
                app.Logger.info('startupFcn','webapp模式')
            end
            % 导入服务端数据库中的课程体系数据
            dbFile = [app.DefaultSavePath,'database.mat'];
            if exist(dbFile,'file') == 2
                app.Info.Value = SetLog(app,sprintf('从服务端数据文件（%s）中获取课程体系！',dbFile));
                load(dbFile,app.DsCurriculum)
                app.Curriculum = eval(app.DsCurriculum);
                app.Tab2.Title = app.DsCurriculum;
            else
                app.Info.Value = SetLog(app,sprintf('找不到服务端数据文件（%s）！',dbFile));
                return
            end
            % 导入已提交的达成度分析数据
            filePath = [app.DefaultSavePath,'app1.mat'];
            if exist(filePath,'file') == 2 
                load(filePath,'savedData')
                if exist('savedData','var') == 1
                    app.Info.Value = SetLog(app,sprintf('从服务端数据文件（%s）中导入%d份达成度分析结果！',filePath,length(savedData)));
                    classes = categorical({savedData.Class});
                    courses = categorical([savedData.Course]);
                    classCat = categories(classes);
                    courseCat = categories(courses);
                    app.Name.Items = {savedData.Info};
                    app.GAResults = savedData;
                    % 初始化app.GAList
                    app.GAList.ColumnName = ['课程名称',classCat'];
                    dat = cell(height(app.Curriculum),length(categories(classes))+1);
                    dat(:,1) = cellstr(app.Curriculum.Name);
                    for i = 1:length(courseCat)
                        idxRow = strcmp(app.Curriculum.Name,courseCat{i});
                        if sum(idxRow) == 1
                            idx1 = [savedData.Course] == courseCat(i);
                            for j = 1:length(classCat)
                                idx2 = classes == classCat{j};
                                idx = idx1&idx2;
                                if any(idx)
                                    dat{idxRow,j+1} = datestr(savedData(find(idx,1,'last')).Datetime);
                                end
                            end
                        else
                            fprintf('进行达成度分析的课程《%s》在课程体系中找不到或不唯一！\n',courseCat{i});
                        end
                    end
                    app.GAList.Data = dat;                    
                end
            else
                app.Info.Value = SetLog(app,sprintf('找不到服务端数据文件："%s”',filePath));
                app.ImportTranscript.Enable = false;
            end
            app.Obj2Way.Enable = 'off';
            info2 = DataHash(filePath);
            info1 = '修订版v1.7';
            app.Version.Text = strcat('（',info1,'@','app1.mat_',info2(end-3:end),'）');
            app.Tab2b.Parent = [];
        end

        % Value changed function: Name
        function NameValueChanged(app, event)
            [~,prompt] = updateCourseInfo(app);
            app.Info.Value = SetLog(app,prompt);
            % 显示课程目标
            objs = app.CurrentCourse.Objectives;
            dat1 = cell(length(objs),3);
            for i = 1:length(objs)
                dat1{i,1} = sprintf('[o%d]',i);
                dat1{i,2} = sprintf('%s',objs{i,1});
            end
            app.ListObj.Data = dat1;
        end

        % Cell edit callback: Obj2Way
        function Obj2WayCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.O2W{indices(1),indices(2)} = newData;
        end

        % Button pushed function: CalcGA
        function CalcGAPushed(app, event)
            [results,w1,app.Transcript.Weights] = calcGA(app);
            updateWeight(app,w1)
            dat = app.ListObj.Data;
            dat(:,end) = num2cell(results);
            app.ListObj.Data = dat;
            % 填写课程目标达成度分析文本模板
            text = TextMaker(app.CurrentCourse,app.Transcript,results);
            app.ResultText.Value = text;
            app.Info.Value = SetLog(app,DrawFig(app));
        end

        % Button pushed function: Save
        function SaveButtonPushed(app, event)
            % 存盘
            saveData = SaveData(app);
            % 本地存盘
            savingFilename = sprintf('《%s》达成度分析本地数据.mat',saveData.Course);
            [file,localPath] = uiputfile(savingFilename,'保存达成度分析数据文件');
            if ~any([localPath,file]) % 当在文件选取窗中点"取消"终止函数执行
                return
            else
                localFile = strcat(localPath,file);
                save(localFile,'saveData')
                app.Info.Value = SetLog(app,sprintf('本地保存数据%s',localFile));
            end
        end

        % Button pushed function: Export
        function ExportButtonPushed(app, event)
            % 生成课程达成度分析报告
            saveData = SaveData(app);
            app.Info.Value = SetLog(app,MakeGA(saveData,app.DefaultSavePath));
        end

        % Cell selection callback: GAList
        function GAListCellSelection(app, event)
            indices = event.Indices;
            idxHaveData = cellfun(@(x)~isempty(x),app.GAList.Data);
            idxHaveData(:,1) = false;           
            if any((indices(2)-1)*size(app.GAList.Data,1)+indices(1)==find(idxHaveData))
                class = app.GAList.ColumnName{indices(2)};
                course = app.Curriculum.Name(indices(1));
                idx1 = [app.GAResults.Course] == course;
                idx2 = strcmp({app.GAResults.Class},class);
                idx = idx1&idx2;
                app.Info.Value = SetLog(app,sprintf('载入%s级《%s》课程达成度分析结果数据（%s）',class,course,datestr(app.GAResults(find(idx,1,'last')).Datetime)));
                saveData = app.GAResults(find(idx,1,'last'));
                % 载入属性
                app.Transcript = saveData.Data.tr;
                app.CurrentCourse = saveData.Data.cc;
                % 载入控件状态
                obj2Way = saveData.Data.obj2Way;
                obj2Way.ColumnEditable = true(1,length(obj2Way.ColumnName)); % 确保关系矩阵可编辑
                app.Obj2Way.Enable = 'on';
                set(app.Obj2Way,obj2Way);
                % 载入达成度分析文本
                app.ResultText.Value = saveData.Data.text;
                % 载入课程目标达成度表
                listObj = saveData.Data.listObj;
                app.ListObj.Data = listObj.Data;
                % 载入评测方式权重表
                if any(strcmp(fieldnames(saveData.Data),'weight'))
                    weight = saveData.Data.weight;
                    weight.ColumnEditable = true(1,length(weight.ColumnName)); % 确保权重矩阵可编辑
                    app.Tab2b.Parent = app.TabGroup2;
                    set(app.Weight,weight);
%                     app.Weight.ColumnName = weight.ColumnName;
%                     app.Weight.ColumnFormat = weight.ColumnFormat;
%                     app.Weight.ColumnWidth = weight.ColumnWidth;
%                     app.Weight.Data = weight.Data;
                else
                    InitWeight(app)
                end
                % 更新课程名称下拉框
                app.Name.Items = flip({app.GAResults(idx).Info});
                app.Name.Value = app.GAResults(find(idx,1,'last')).Info; % 当前显示值
                % 更新课程信息及日志
                [~,prompt] = updateCourseInfo(app);
                app.Info.Value = SetLog(app,prompt);
                % 更新课程目标达成度结果柱状图及日志
                app.Info.Value = SetLog(app,DrawFig(app));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainUI = uifigure('Visible', 'off');
            app.MainUI.Name = 'MATLAB App';
            app.Title = uilabel(app.MainUI);
            app.Title.Text = '能源化学工程专业课程质量评价结果管理程序';
            app.Label_2 = uilabel(app.MainUI);
            app.Label_2.Text = '数据记录';
            app.Name = uidropdown(app.MainUI);
            app.Name.ValueChangedFcn = createCallbackFcn(app, @NameValueChanged, true);
            app.ResultText = uitextarea(app.MainUI);
            app.Info = uitextarea(app.MainUI);
            app.Label_3 = uilabel(app.MainUI);
            app.Label_3.Text = '课程代码';
            app.Code = uieditfield(app.MainUI, 'text');
            app.Label_4 = uilabel(app.MainUI);
            app.Label_4.Text = '课程类型';
            app.Category = uieditfield(app.MainUI, 'text');
            app.Label_5 = uilabel(app.MainUI);
            app.Label_5.Text = '课程性质';
            app.CompulsoryOrNot = uieditfield(app.MainUI, 'text');
            app.Label_6 = uilabel(app.MainUI);
            app.Label_6.Text = '课程学分';
            app.Credit = uieditfield(app.MainUI, 'text');
            app.Label_7 = uilabel(app.MainUI);
            app.Label_7.Text = '课程学时';
            app.Hours = uieditfield(app.MainUI, 'text');
            app.Label_8 = uilabel(app.MainUI);
            app.Label_8.Text = '开课学期';
            app.Semester = uieditfield(app.MainUI, 'text');
            app.CalcGA = uibutton(app.MainUI, 'push');
            app.CalcGA.ButtonPushedFcn = createCallbackFcn(app, @CalcGAPushed, true);
            app.CalcGA.Text = '计算达成度';
            app.Save = uibutton(app.MainUI, 'push');
            app.Save.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.Save.Text = '本地保存';
            app.Image = uiimage(app.MainUI);
            app.Image.ImageSource = 'bedge_small.png';
            app.Version = uilabel(app.MainUI);
            app.TabGroup = uitabgroup(app.MainUI);
            app.Tab1a = uitab(app.TabGroup);
            app.Tab1a.Title = '课程目标达成度表';
            app.ListObj = uitable(app.Tab1a);
            app.ListObj.ColumnName = {'编号'; '课程目标'; '达成度'};
            app.ListObj.RowName = {};
            app.Tab1b = uitab(app.TabGroup);
            app.Tab1b.Title = '达成度变化图';
            app.UIAxes = uiaxes(app.Tab1b);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.Export = uibutton(app.MainUI, 'push');
            app.Export.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.Export.Text = '输出报告';
            app.Copyright = uilabel(app.MainUI);
            app.Copyright.Text = 'Copyright © 2021-2022 SCUT.EChE.PII. All rights reserved.';
            app.TabGroup2 = uitabgroup(app.MainUI);
            app.Tab2 = uitab(app.TabGroup2);
            app.Tab2.Title = 'Tab';
            app.GAList = uitable(app.Tab2);
            app.GAList.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.GAList.RowName = {};
            app.GAList.CellSelectionCallback = createCallbackFcn(app, @GAListCellSelection, true);
            app.Tab2a = uitab(app.TabGroup2);
            app.Tab2a.Title = '评测项与课程目标的关系';
            app.Obj2Way = uitable(app.Tab2a);
            app.Obj2Way.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Obj2Way.RowName = {};
            app.Obj2Way.CellEditCallback = createCallbackFcn(app, @Obj2WayCellEdit, true);
            app.Tab2b = uitab(app.TabGroup2);
            app.Tab2b.Title = '评测方式权重';
            app.Weight = uitable(app.Tab2b);
            app.Weight.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Weight.RowName = {};
            % Show the figure after all components are created
            app.MainUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GoalAdmin_main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainUI)
        end
    end
end