classdef Goal_UI_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Title             matlab.ui.control.Label
        Label_2           matlab.ui.control.Label
        Name              matlab.ui.control.DropDown
        ImportTranscript  matlab.ui.control.Button
        ResultText        matlab.ui.control.TextArea
        Info              matlab.ui.control.TextArea
        Label_3           matlab.ui.control.Label
        Code              matlab.ui.control.EditField
        Label_4           matlab.ui.control.Label
        Category          matlab.ui.control.EditField
        Label_5           matlab.ui.control.Label
        CompulsoryOrNot   matlab.ui.control.EditField
        Label_6           matlab.ui.control.Label
        Credit            matlab.ui.control.EditField
        Label_7           matlab.ui.control.Label
        Hours             matlab.ui.control.EditField
        Label_8           matlab.ui.control.Label
        Semester          matlab.ui.control.EditField
        CalcGA            matlab.ui.control.Button
        Load              matlab.ui.control.Button
        Save              matlab.ui.control.Button
        Image             matlab.ui.control.Image
        Version           matlab.ui.control.Label
        ImportTemplate    matlab.ui.control.Button
        TabGroup          matlab.ui.container.TabGroup
        Tab1a             matlab.ui.container.Tab
        ListObj           matlab.ui.control.Table
        Tab1b             matlab.ui.container.Tab
        UIAxes            matlab.ui.control.UIAxes
        Tab1c             matlab.ui.container.Tab
        UIAxes2           matlab.ui.control.UIAxes
        Export            matlab.ui.control.Button
        Copyright         matlab.ui.control.Label
        TabGroup2         matlab.ui.container.TabGroup
        Tab2a             matlab.ui.container.Tab
        Obj2Way           matlab.ui.control.Table
        Tab2b             matlab.ui.container.Tab
        Weight            matlab.ui.control.Table
        Tab2c             matlab.ui.container.Tab
        Criteria          matlab.ui.control.TextArea
        Tab2d             matlab.ui.container.Tab
        Basis             matlab.ui.control.TextArea
        Label_9           matlab.ui.control.Label
        ReleaseYear       matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        Curriculum;
        Indicators;
        DefaultSavePath = '';
        AppInfo;
        CurrentCourse Course
        Transcript Transcript
        O2W % 课程目标与评测方式的关系矩阵
        GoalAchieve % 课程目标达成度计算结果公共变量
        TranscriptLoaded;
        Logger log4m
    end
    
    methods (Access = private)
        
        function docType = getType(app)
            switch app.Category.Value
                case('集中实践教学')
                    docType = '实习';
                otherwise
                    if ~isempty(regexp(app.CurrentCourse.Title,'实验','once'))
                        docType = '实验';
                    else
                        docType = '理论';
                    end                 
            end
        end
        
        function [objAchieve,dat4,oA4EachStudent] = calcGA(app,opt)
            % 预设参数
            if ~exist('opt','var')
                opt = 1; % 先算班级平均分再据此计算达成度
            end
            % 获得成绩矩阵
            scores = app.Transcript.Detail{:,4:end};
            scores(any(isnan(scores),2),:) = []; % 删除成绩单中有NaN的记录
            % 获得满分值
            fullValues = app.Transcript.SubPoints;
            % 获得关系矩阵
            o2w = app.Obj2Way.Data(:,2:end);
            % 获得权重矩阵
            weight = app.Weight.Data(:,2:end);
            % 检查变量尺寸
            if ~isequal(size(scores,2),length(fullValues),size(o2w,1))
                warning('成绩矩阵列数（%d）、分值向量（%d）和关系矩阵的行数（%d）不相等！',...
                    size(scores,2),length(fullValues),size(o2w,1));
                return
            end
            objAchieve = zeros(size(o2w,2),1);
            dat4 = weight;
            switch opt
                case(1)
                    x = mean(scores)./fullValues; % 计算各评测项的得分率
                    objAchieve = CalcObjAchieve(x);
                case(2) % 以每个同学成绩计算各项达成度
                    oA4EachStudent = cell(size(scores,1),1);
                    for i = 1:size(scores,1)
                        x = scores(i,:)./fullValues;
                        oA4EachStudent{i} = CalcObjAchieve(x)';
                    end
                    oA4EachStudent = cell2mat(oA4EachStudent);
                    objAchieve = mean(oA4EachStudent)';
            end
            
            function y = CalcObjAchieve(x)
                y = zeros(size(o2w,2),1);
                % 计算各课程目标达成度
                for iObj = 1:size(o2w,2)
                    idx1 = [o2w{:,iObj}]; % 与该课程目标相关的评测项
                    firstLetterAll = categorical(cellfun(@(x){x(1)},...
                        cellstr(app.Transcript.VarNames)));
                    firstLetterCat = categories(firstLetterAll);
                    subPoint = zeros(length(firstLetterCat),1);
                    for iWay = 1:length(firstLetterCat)
                        % 选定某评测方式中各项
                        idx2 = firstLetterAll==firstLetterCat{iWay};
                        idx = idx1 & idx2;
                        if any(idx)
                            w = fullValues(idx)/sum(fullValues(idx));
                            subPoint(iWay) = sum(w.*x(idx));
                        else
                            subPoint(iWay) = nan;
                        end
                    end
                    idxNaN = isnan(subPoint);
                    subWeight = cell2mat(weight(:,iObj));
                    subWeight(idxNaN) = nan;
                    subWeight = subWeight/sum(subWeight,'omitnan');
                    dat4(:,iObj) = num2cell(subWeight);
                    y(iObj) = sum(subWeight.*subPoint,'omitnan');
                end
            end
                    
        end
        
        function [idx,prompt] = LoadLatestCourse(app)
            load(strcat(app.DefaultSavePath,'app.mat'),'cs')
            idx1 = strcmp(app.Name.Value,string({cs.Title}));
            idx2 = strcmp(app.ReleaseYear.Value,[cs.ReleaseYear]);
            idx = idx1&idx2;
            if isempty(app.Name.Value) || ~any(idx)
                prompt = '课程选择为空！';
                return
            end
            % 更新最新课程
            app.CurrentCourse = cs(find(idx,1,'last'));
        end
        
        function prompt = updateCourseInfo(app)
            % 更新控件值
            app.Code.Value = app.CurrentCourse.Code;
            app.Category.Value = app.CurrentCourse.Category;
            if app.Category.Value == "选修课"
                app.CompulsoryOrNot.Value = "选修";
            else
                app.CompulsoryOrNot.Value = "必修";
            end
            app.Credit.Value = app.CurrentCourse.Credits;
            app.Hours.Value = app.CurrentCourse.ClassHour;
            app.Semester.Value = app.CurrentCourse.Semester;
            prompt = sprintf('获取%s课《%s》的课程信息！',app.CompulsoryOrNot.Value,app.Name.Value);
        end
        
        function DrawBoxPlot(app,GADetail)
            boxplot(app.UIAxes2,GADetail.Data,GADetail.xLabel);
            xlabel(app.UIAxes2,GADetail.xTitle);
            ylabel(app.UIAxes2,GADetail.yTitle);
            title(app.UIAxes2,GADetail.Title);
            exportgraphics(app.UIAxes2,GADetail.FigPath)
            info1 = sprintf('保存课程目标达成度箱式图于服务端%s',GADetail.FigPath);
            app.Info.Value = info1;
            app.Logger.info('DrawBoxPlot',info1)
        end
        
        function prompt = DrawFig(app,GAResult)
            prompt = '';
            if ~exist('GAResult','var')
                prompt = '提取达成度历史数据；';
                currentCourse = app.CurrentCourse;
                currentClass = app.Transcript.Class;
                % 提取当前数据
                GAResult.xLabel = app.ListObj.Data(:,1);
                GAResult.Data = cell2mat(app.ListObj.Data(:,end));
                GAResult.Legend = sprintf('%s',app.Transcript.Class);
                GAResult.Title = sprintf('《%s》课程目标达成度',app.Transcript.Name);
                GAResult.FigPath = [app.DefaultSavePath,'GAResult.png'];
                % 提取历史数据
                pathFile = strcat(app.DefaultSavePath,'app1.mat');
                if exist(pathFile,'file') == 2
                    load(pathFile,'savedData')
                    idxCourse = strcmp(currentCourse.Title,[savedData.Course]);
                    % 提取存盘数据中的指定课程（按课程名称筛选）
                    extractCourses = savedData(idxCourse);
                    % 检查课程支撑的指标点是否一致
                    keptIdx = arrayfun(@(x)isequal(x.Data.cc.Outcomes,currentCourse.Outcomes),extractCourses);
                    extractCourses = extractCourses(keptIdx);
                    % 排除当前年级之后的数据
                    classList4GivenCourse = {extractCourses.Class};
                    delIdx = cellfun(@(x)str2double(x),classList4GivenCourse) >= str2double(currentClass);
                    classList4GivenCourse(delIdx) = [];
                    catList = categories(categorical(classList4GivenCourse));
                    iCourses = cellfun(@(x)find(strcmp(x,{extractCourses.Class}),1,'last'),catList);
                    if ~isempty(iCourses) || isa(iCourses,'numeric')
                        extractData = extractCourses(iCourses);
                        GAResult.Legend = [GAResult.Legend,{extractData.Class}];
                        for i = 1:length(extractData)
                            GAResult.Data = [GAResult.Data,cell2mat(extractData(i).Data.listObj.Data(:,3))];
                        end
                    end
                else
                    app.Logger.info('DrawFig',sprintf('服务端%s文件不存在！',pathFile));
                end
                app.GoalAchieve.GAResult = GAResult;
            end
            % 绘制课程目标达成度柱状图
            bar(app.UIAxes,GAResult.Data);
            xticklabels(app.UIAxes,GAResult.xLabel);
            title(app.UIAxes,GAResult.Title);
            legend(app.UIAxes,GAResult.Legend,"Location","eastoutside");
            if isempty(app.Tab1b.Parent)
                app.Tab1b.Parent = app.TabGroup;
            end
            exportgraphics(app.UIAxes,GAResult.FigPath)
            prompt = sprintf('%s保存课程目标达成度柱状图于服务端%s',...
                prompt,GAResult.FigPath);
            app.Info.Value = prompt;
            app.Logger.info('DrawFig',prompt);
        end
        
        function [saveData,saveData1] = SaveData(app)
            % UI控件数据
            % 注意app designer中的对象不能直接保存，例如obj2Way = app.Obj2Way;
            propsList = {'Data','ColumnName','ColumnFormat','ColumnWidth','ColumnEditable'};
            obj2Way = getAppTabProps(app.Obj2Way,propsList);
            weight = getAppTabProps(app.Weight,propsList);
            listObj.Data = app.ListObj.Data;
            text = app.ResultText.Value;
            criteria = app.Criteria.Value; % issue#I65A5K 新增变量保存"评分标准"数据
            basis = app.Basis.Value; % issue#I65A5K 新增变量保存"评价依据"数据
            GAResult = app.GoalAchieve.GAResult;
            GADetail = app.GoalAchieve.GADetail;
            % UI内部变量
            cc = app.CurrentCourse;
            tr = app.Transcript;
            % 创建存盘数据结构
            saveData.Info = sprintf('%s（%s）：%s',cc.Title,tr.Class,datestr(now));
            saveData.Course = cc.Title;
            saveData.Class = tr.Class;
            saveData.Datetime = datetime(now,"ConvertFrom","datenum");
            saveData1 = saveData;
            savingVarList = {'obj2Way','weight','listObj','cc','tr','text'};
            for iVar = 1:length(savingVarList)
                saveData.Data.(savingVarList{iVar}) = eval(savingVarList{iVar});
            end
            % saveData保持数据与之前一致，新增数据保存于saveData1
            savingVarList1 = {'obj2Way','weight','listObj','cc','tr','text',...
                'criteria','basis','GAResult','GADetail'};
            for iVar = 1:length(savingVarList1)
                saveData1.Data.(savingVarList1{iVar}) = eval(savingVarList1{iVar});
            end
                        
            function props = getAppTabProps(tabObj,propsList)
                % 输入参数检查
                %
                props = struct;
                for i = 1:length(propsList)
                    props.(propsList{i}) = get(tabObj,propsList{i});
                end
            end
            
        end
        
        function InitWeight(app)
            % 获得满分值
            fullValues = app.Transcript.SubPoints;
            % 获得关系矩阵
            o2w = cell2mat(app.Obj2Way.Data(:,2:end));
            % 激活显示评测方式权重表的标签
            app.Tab2b.Parent = app.TabGroup2;
            % 计算评测方式权重值
            firstLetterAll = categorical(cellfun(@(x){x(1)},cellstr(app.Transcript.VarNames)));
            firstLetterCat = categories(firstLetterAll);
            w1 = zeros(length(firstLetterCat),size(o2w,2));
            for iObj = 1:size(o2w,2)
                idx1 = o2w(:,iObj);
                w0 = fullValues/sum(fullValues(idx1));
                for iWay = 1:length(firstLetterCat)
                    % 选定某评测方式中各项
                    idx2 = firstLetterAll==firstLetterCat{iWay};
                    idx = idx1 & idx2';
                    if any(idx)
                        w1(iWay,iObj) = sum(w0(idx));
                    else
                        w1(iWay,iObj) = nan;
                    end
                end
            end
            % 构造表数据
            colNum = size(app.ListObj.Data,1)+1;
            rowNum = length(app.Transcript.Definition);
            dat3 = cell(rowNum,colNum);
            for iRow = 1:rowNum
                dat3{iRow,1} = char(app.Transcript.Definition(iRow).Name);
            end
            dat3(:,2:end) = num2cell(w1);
            % 设置表参数
            colNames = cell(1,colNum);
            colWidths = cell(1,colNum);
            colFormats = cell(1,colNum);
            colEditable = true(1,colNum);
            colNames{1} = '评测方式';
            colWidths{1} = 'auto'; 
            colFormats{1} = 'char';            
            for iCol = 2:length(colNames)
                colNames{iCol} = sprintf('[o%d]',iCol-1);
                colWidths{iCol} = 60;
                colFormats{iCol} = 'numeric';
            end
            app.Weight.ColumnName = colNames;
            app.Weight.ColumnWidth = colWidths;
            app.Weight.ColumnEditable = colEditable;
            % 填入表数据
            app.Weight.Data = dat3;
        end
        
        function prompt = updateWeight(app,data)
            app.Weight.Data(:,2:end) = data;
            prompt = sprintf('更新评测方式权重列表！');
        end
        
        function ButtonEnable(app,trigger)
            trigger = logical(trigger);
            app.ImportTranscript.Enable = trigger(1);
            app.CalcGA.Enable = trigger(2);
            app.Save.Enable = trigger(3);
            app.Export.Enable = trigger(4);
        end
    end
    
    methods (Access = public)
        
        function result = SetLog(app,text)
            txt1 = sprintf('[%s]：%s',datestr(now,'HH:MM:SS'),char(text));
            oldMsg = app.AppInfo;
            if isempty(oldMsg)
                result = {txt1};
            else
                result = [cellstr(txt1);oldMsg];
            end
            app.AppInfo = result;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            homePath = cd;
            app.Logger = log4m.getLogger;
            app.Logger.info('Goal_UI_main.mlapp','启动程序')
            if contains(homePath,'\Git\EnergyChE')
                app.DefaultSavePath = [homePath,'\'];
                app.Logger.info('startupFcn','本地调试模式')
            else
                idx = regexp(homePath,'\');
                app.DefaultSavePath = homePath(1:idx(end-3));
                app.Logger.info('startupFcn','webapp模式')
            end
            filePath = [app.DefaultSavePath,'app.mat'];
            if exist(filePath,'file') == 2 
                load(filePath,'cs')
                if exist('cs','var') == 1
                    itms2 = string(categories(categorical(string({cs.ReleaseYear}))));
                    info = sprintf('从数据文件（%s）中获取已提交教纲的所属培养方案年度列表！',filePath);
                    app.Info.Value = info;
                    app.Logger.info('startupFcn',info)
                    ButtonEnable(app,[0 0 0 0]);
                end
                app.Name.Enable = false;
                app.ReleaseYear.Items = ["";itms2];
                info1 = sprintf('更新控件app.ReleaseYear下拉菜单%d项内容',length(itms2));
                app.Info.Value = info1;
                app.Logger.info('startupFcn',info1)
            else
                info = sprintf('找不到文件："%s”',filePath);
                app.Info.Value = info;
                app.Logger.info('startupFcn',info)
                app.ImportTranscript.Enable = false;
            end
            app.Obj2Way.Enable = 'off';
            % 应用程序版本信息
            app.AppInfo = '修订版v4.1';
            app.Version.Text = app.AppInfo;
            app.Logger.info('startupFcn',app.AppInfo)    
            app.Tab2b.Parent = [];
            app.Tab1b.Parent = [];
            app.TranscriptLoaded = false;
        end

        % Button pushed function: ImportTranscript
        function ImportTranscriptButtonPushed(app, event)
            app.TranscriptLoaded = true;
            [app.Transcript,prompt] = importTranscript(app.Name.Value);
            if isempty(prompt)
                return
            end
            info = sprintf('%s',prompt);
            app.Info.Value = info;
            app.Logger.info('ImportTranscriptButtonPushed',info)
            app.Obj2Way.Enable = 'on';
            % 课程目标
            colNum = size(app.ListObj.Data,1)+1;
            colNames = cell(1,colNum);
            colWidths = cell(1,colNum);
            colFormats = cell(1,colNum);
            colEditable = true(1,colNum);
            colNames{1} = '评测方式';
            colWidths{1} = 'auto';
            colFormats{1} = 'char';
            for iCol = 2:length(colNames)
                colNames{iCol} = sprintf('[o%d]',iCol-1);
                colWidths{iCol} = 40;
                colFormats{iCol} = 'logical';
            end
            app.Obj2Way.ColumnName = colNames;
            app.Obj2Way.ColumnWidth = colWidths;
            app.Obj2Way.ColumnFormat = colFormats;
            app.Obj2Way.ColumnEditable = colEditable;
            EWCodes = app.Transcript.Detail.Properties.VariableNames;
            EWDescriptions = app.Transcript.Detail.Properties.VariableDescriptions;
            EWCodes(1:3) = [];
            EWDescriptions(1:3) = [];
            dat2 = cell(length(EWCodes),colNum);
            for iRow = 1:length(EWCodes)
                dat2{iRow,1} = [EWCodes{iRow},'：',EWDescriptions{iRow}];
                dat2(iRow,2:end) = {false};
            end
            app.Obj2Way.Data = dat2;
            app.O2W = app.Obj2Way.Data; % 初始化关系矩阵O2W
        end

        % Value changed function: Name
        function NameValueChanged(app, event)
            LoadLatestCourse(app);
            info = updateCourseInfo(app);
            app.Info.Value = info;
            app.Logger.info('NameValueChanged',info)
            ButtonEnable(app,[1 0 0 0]);
            % 显示当前教纲hash
            info1 = DataHash(app.CurrentCourse);
            app.Version.Text = sprintf('%s（教纲数据%s）',app.AppInfo,info1(end-3:end));
            % 显示课程目标
            objs = app.CurrentCourse.Objectives;
            dat1 = cell(length(objs),3);
            for i = 1:length(objs)
                dat1{i,1} = sprintf('[o%d]',i);
                dat1{i,2} = sprintf('%s',objs{i,1});
            end
            app.ListObj.Data = dat1;
            % 显示缺省评测标准内容
            app.Criteria.Value = app.CurrentCourse.ExamMethod;
            % 显示缺省评测依据内容
            app.Basis.Value = TextMaker([],[],[],1);
        end

        % Cell edit callback: Obj2Way
        function Obj2WayCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.O2W{indices(1),indices(2)} = newData;
            ButtonEnable(app,[1 1 0 0]);
            % 初始化评测方式权重表
            InitWeight(app)
        end

        % Button pushed function: CalcGA
        function CalcGAPushed(app, event)
            app.Tab1b.Parent = app.TabGroup;
            [results,wData,details] = calcGA(app,2);
            updateWeight(app,wData)
            dat = app.ListObj.Data;
            dat(:,end) = num2cell(results);
            app.ListObj.Data = dat;
            % 绘制箱式图
            nObj = length(results);
            objNumLabel = arrayfun(@(x)sprintf('[o%d]',x),[1:nObj],...
                'UniformOutput',false);
            GADetail.xLabel = objNumLabel;
            GADetail.Data = details;
            GADetail.xTitle = '课程目标编号';
            GADetail.yTitle = '达成度';
            GADetail.Title = sprintf('能源化工专业%s级《%s》课程目标达成度箱式图',...
                app.Transcript.Class,app.Transcript.Name);
            GADetail.FigPath = [app.DefaultSavePath,'GADetail.png'];
            app.GoalAchieve.GADetail = GADetail;
            DrawBoxPlot(app,GADetail)
            % 绘制达成度结果历史柱状图
            info2 = DrawFig(app);
            app.Info.Value = info2;
            app.Logger.info('CalcGAPushed',info2)
            % 填写课程目标达成度分析的持续改进文本模板
            app.ResultText.Value = '持续改进的具体措施包括如下：';
            % 激活UI中的全部按钮
            ButtonEnable(app,[1 1 1 1]);
        end

        % Button pushed function: Load
        function LoadButtonPushed(app, event)
            [file,path] = uigetfile('app1local.mat','MultiSelect',"off",'载入本地数据文件');
            if ~any([path,file]) % 当在文件选取窗中点"取消"终止函数执行
                return
            end            
            app.TranscriptLoaded = true;
            pathFile = strcat(path,file);
            info = sprintf('从%s中载入数据',pathFile);
            app.Info.Value = info;
            app.Logger.info('LoadButtonPushed',info)
            load(pathFile,'saveData')
            % 载入属性
            app.Transcript = saveData.Data.tr;
            app.CurrentCourse = saveData.Data.cc;
            % 载入控件状态
            ButtonEnable(app,[1 1 1 1]);
            obj2Way = saveData.Data.obj2Way;
            obj2Way.ColumnEditable = true(1,length(obj2Way.ColumnName)); % 确保关系矩阵可编辑
            app.Obj2Way.Enable = 'on';
            set(app.Obj2Way,obj2Way);
            %
            app.ResultText.Value = saveData.Data.text;
            %
            listObj = saveData.Data.listObj;
            app.ListObj.Data = listObj.Data;
            %
            if any(strcmp(fieldnames(saveData.Data),'weight'))
                weight = saveData.Data.weight;
                weight.ColumnEditable = true(1,length(weight.ColumnName)); % 确保权重矩阵可编辑
                app.Tab2b.Parent = app.TabGroup2;
                set(app.Weight,weight);
            else
                InitWeight(app)
            end
            %
            if IsVarInMATFile('saveData1',pathFile)  % 存盘数据中是否含有saveData1变量
                load(pathFile,'saveData1')
                app.Criteria.Value = saveData1.Data.criteria;
                app.Basis.Value = saveData1.Data.basis;
                app.GoalAchieve.GAResult = saveData1.Data.GAResult;
                DrawFig(app,saveData1.Data.GAResult);
                app.GoalAchieve.GADetail = saveData1.Data.GADetail;
                DrawBoxPlot(app,saveData1.Data.GADetail);
            end
%             %
%             if any(strcmp(fieldnames(saveData.Data),'criteria'))
%                 app.Criteria.Value = saveData.Data.criteria;
%             end
%             %
%             if any(strcmp(fieldnames(saveData.Data),'basis'))
%                 app.Basis.Value = saveData.Data.basis;
%             end
            %
            app.ReleaseYear.Value = app.CurrentCourse.ReleaseYear;
            ReleaseYearValueChanged(app);
            app.Name.Value = app.CurrentCourse.Title;
            updateCourseInfo(app);
%             info1 = DrawFig(app);
%             app.Info.Value = info1;
%             app.Logger.info('LoadButtonPused',info1)
        end

        % Button pushed function: Save
        function SaveButtonPushed(app, event)
            % 本地存盘
            fileName = sprintf('app1local《%s》（%s）.mat',app.CurrentCourse.Title,app.Transcript.Class);
            [file,localPath] = uiputfile(fileName,'选定要保存的数据文件名称');
            if ~any([localPath,file]) % 当在文件选取窗中点"取消"终止函数执行
                return
            else
                localFile = strcat(localPath,file);
                [saveData,saveData1] = SaveData(app);
                save(localFile,'saveData','saveData1')
                info = sprintf('本地保存数据%s',localFile);
                app.Info.Value = info;
                app.Logger.info('SaveButtonPushed',info)
            end
        end

        % Button pushed function: ImportTemplate
        function ImportTemplateButtonPushed(app, event)
            dat = cell(4,4);
            dat(4,1:3) = {'班级','学号','姓名'};
            dat(:,4) = {'评测方式>','大题>','小题-打分点>','满分值>'};
            fileName = '成绩单.xlsx';
            if ~isempty(app.Name.Value)
                if app.TranscriptLoaded
                    fileName = sprintf('《%s》（%s）%s',app.Name.Value,...
                        app.Transcript.Class,fileName);
                else
                    fileName = sprintf('《%s》%s',app.Name.Value,fileName);
                end
            end
            [file,path] = uiputfile(fileName,'下载成绩单模板');
            if ~any([file,path]) % 当在文件选取窗中点"取消"终止函数执行
                return
            end
            writecell(dat,[path,file])
            info = sprintf('下载文件"%sf到本地路径%s！',file,path);
            app.Info.Value = info;
            app.Logger.info('ImportTemplateButtonPushed',info)
        end

        % Button pushed function: Export
        function ExportButtonPushed(app, event)
            % 存盘数据
            [saveData,saveData1] = SaveData(app);
            % 设定远程数据文件路径
            pathFile = strcat(app.DefaultSavePath,'app1.mat');
            if exist(pathFile,'file') == 2 % 将当前数据添加到远程数据文件
                load(pathFile,'savedData')
                savedData = [savedData,saveData];
                if IsVarInMATFile('savedData1',pathFile) % 检查pathFile中是否存有变量savedData1
                    load(pathFile,'savedData1')
                    savedData1 = [savedData1,saveData1];
                else
                    savedData1 = saveData1;
                end
            else % 新建远程数据文件
                savedData = saveData;
                savedData1 = saveData1;
            end
            % 远程保存数据
            save(pathFile,'savedData','savedData1')
            info1 = sprintf('程序数据保存于服务端%s',pathFile);
            app.Info.Value = info1;
            app.Logger.info('ExportButtonPushed',info1)
            % 生成课程达成度分析报告
            info2 = MakeGA2(saveData1,app.DefaultSavePath);
            app.Info.Value = info2;
            app.Logger.info('ExportButtonPushed',info2)            
        end

        % Cell edit callback: Weight
        function WeightCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.Weight.Data{indices(1),indices(2)} = newData;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.Logger.info('UIFigureCloseRequest','关闭')
            delete(app)
        end

        % Value changed function: ReleaseYear
        function ReleaseYearValueChanged(app, event)
            value = app.ReleaseYear.Value;
            load(strcat(app.DefaultSavePath,'app.mat'),'cs')
            idx = strcmp(value,[cs.ReleaseYear]);
            itms1 = string(categories(categorical(string({cs(idx).Title}))));
            app.Name.Items = ["";itms1]; % 注意categories()生成列向量故连接符为分号
            app.Name.Enable = true;
            info = sprintf('从%d份提交教纲中选取属于%s年度培养方案的%d份课程更新为控件app.Name下拉框列表',...
                length(cs),value,length(itms1));
            app.Info.Value = info;
            app.Logger.info('ReleaseYearValueChanged',info)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = '能源化学工程专业课程质量评价';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '课程名称';
            app.Name = uidropdown(app.UIFigure);
            app.Name.ValueChangedFcn = createCallbackFcn(app, @NameValueChanged, true);
            app.ImportTranscript = uibutton(app.UIFigure, 'push');
            app.ImportTranscript.ButtonPushedFcn = createCallbackFcn(app, @ImportTranscriptButtonPushed, true);
            app.ImportTranscript.Text = '导入成绩单';
            app.ResultText = uitextarea(app.UIFigure);
            app.Info = uitextarea(app.UIFigure);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '课程代码';
            app.Code = uieditfield(app.UIFigure, 'text');
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '课程类型';
            app.Category = uieditfield(app.UIFigure, 'text');
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '课程性质';
            app.CompulsoryOrNot = uieditfield(app.UIFigure, 'text');
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '课程学分';
            app.Credit = uieditfield(app.UIFigure, 'text');
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '课程学时';
            app.Hours = uieditfield(app.UIFigure, 'text');
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '开课学期';
            app.Semester = uieditfield(app.UIFigure, 'text');
            app.CalcGA = uibutton(app.UIFigure, 'push');
            app.CalcGA.ButtonPushedFcn = createCallbackFcn(app, @CalcGAPushed, true);
            app.CalcGA.Text = '计算达成度';
            app.Load = uibutton(app.UIFigure, 'push');
            app.Load.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.Load.Text = '载入本地数据';
            app.Save = uibutton(app.UIFigure, 'push');
            app.Save.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.Save.Text = '本地保存';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'bedge_small.png';
            app.Version = uilabel(app.UIFigure);
            app.ImportTemplate = uibutton(app.UIFigure, 'push');
            app.ImportTemplate.ButtonPushedFcn = createCallbackFcn(app, @ImportTemplateButtonPushed, true);
            app.ImportTemplate.Text = '成绩单模板';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab1a = uitab(app.TabGroup);
            app.Tab1a.Title = '课程目标达成度表';
            app.ListObj = uitable(app.Tab1a);
            app.ListObj.ColumnName = {'编号'; '课程目标'; '达成度'};
            app.ListObj.RowName = {};
            app.Tab1b = uitab(app.TabGroup);
            app.Tab1b.Title = '达成度变化图';
            app.UIAxes = uiaxes(app.Tab1b);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.YTick = [0 0.5 1];
            app.Tab1c = uitab(app.TabGroup);
            app.Tab1c.Title = '达成度箱式图';
            app.UIAxes2 = uiaxes(app.Tab1c);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.Export = uibutton(app.UIFigure, 'push');
            app.Export.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.Export.Text = '输出报告';
            app.Copyright = uilabel(app.UIFigure);
            app.Copyright.Text = 'Copyright © 2021-2022 SCUT.EChE.PII. All rights reserved.';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.Tab2a = uitab(app.TabGroup2);
            app.Tab2a.Title = '评测项与课程目标的关系';
            app.Obj2Way = uitable(app.Tab2a);
            app.Obj2Way.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Obj2Way.RowName = {};
            app.Obj2Way.CellEditCallback = createCallbackFcn(app, @Obj2WayCellEdit, true);
            app.Tab2b = uitab(app.TabGroup2);
            app.Tab2b.Title = '评测方式权重';
            app.Weight = uitable(app.Tab2b);
            app.Weight.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Weight.RowName = {};
            app.Weight.CellEditCallback = createCallbackFcn(app, @WeightCellEdit, true);
            app.Tab2c = uitab(app.TabGroup2);
            app.Tab2c.Title = '评测标准';
            app.Criteria = uitextarea(app.Tab2c);
            app.Tab2d = uitab(app.TabGroup2);
            app.Tab2d.Title = '评测依据及方法';
            app.Basis = uitextarea(app.Tab2d);
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '方案年份';
            app.ReleaseYear = uidropdown(app.UIFigure);
            app.ReleaseYear.Items = {};
            app.ReleaseYear.ValueChangedFcn = createCallbackFcn(app, @ReleaseYearValueChanged, true);
            app.ReleaseYear.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Goal_UI_main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end