classdef Syllabus_Admin_UI_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mainUI             matlab.ui.Figure
        LabelHeadTitle     matlab.ui.control.Label
        Label_3            matlab.ui.control.Label
        Code               matlab.ui.control.EditField
        Label_4            matlab.ui.control.Label
        Type               matlab.ui.control.EditField
        Label_5            matlab.ui.control.Label
        CompulsoryOrNot    matlab.ui.control.EditField
        Label_6            matlab.ui.control.Label
        Hours              matlab.ui.control.EditField
        Label_7            matlab.ui.control.Label
        Credit             matlab.ui.control.EditField
        Label_8            matlab.ui.control.Label
        Semester           matlab.ui.control.EditField
        Label_9            matlab.ui.control.Label
        Language           matlab.ui.control.EditField
        Label_11           matlab.ui.control.Label
        Name               matlab.ui.control.DropDown
        Download           matlab.ui.control.Button
        TabGroup           matlab.ui.container.TabGroup
        Tab1               matlab.ui.container.Tab
        TabReq2Obj         matlab.ui.control.Table
        Tab2               matlab.ui.container.Tab
        Description        matlab.ui.control.TextArea
        Tab3               matlab.ui.container.Tab
        Content            matlab.ui.control.TextArea
        Tab4               matlab.ui.container.Tab
        ExpTeach           matlab.ui.control.TextArea
        Tab5               matlab.ui.container.Tab
        TeachMethod        matlab.ui.control.TextArea
        Tab6               matlab.ui.container.Tab
        ExamMethod         matlab.ui.control.TextArea
        Benchmark          matlab.ui.control.Table
        Tab7               matlab.ui.container.Tab
        Textbook           matlab.ui.control.TextArea
        Tab8               matlab.ui.container.Tab
        Notices            matlab.ui.control.TextArea
        Tab9               matlab.ui.container.Tab
        ExpDetails         matlab.ui.control.Table
        Label_15           matlab.ui.control.Label
        ExpNum             matlab.ui.control.Spinner
        Image              matlab.ui.control.Image
        Version            matlab.ui.control.Label
        LocalSave          matlab.ui.control.Button
        Copyright          matlab.ui.control.Label
        Info               matlab.ui.control.TextArea
        Label_16           matlab.ui.control.Label
        DispPrerequisites  matlab.ui.control.TextArea
        CurriculumList     matlab.ui.control.Table
    end

    
    properties (Access = private)
        DsCurriculum = 'db_Curriculum2021'; % 课程体系描述字段
        DsIndicators = 'db_Indicators2021'; % 毕业要求指标点（内涵观测点）
        Curriculum;
        Indicators;
        DefaultSavePath = '';
        CurrentCourse Course
        AllCourses Course
    end
    
    properties (Access = public)
        AppInfo; % 日志记录
    end
    
    methods (Access = private)
              
        function docType = updateTabs(app)
            app.Tab3.Parent = app.TabGroup;
            app.Tab9.Parent = [];
            switch app.CurrentCourse.Category
                case('集中实践教学')
                    app.Tab4.Title = '实习方式';
                    app.Tab5.Title = '实习地点';                    
                    app.Tab8.Parent = app.TabGroup;
                    docType = '实习';
                otherwise
                    if ~isempty(regexp(app.CurrentCourse.Title,'实验','once'))
                        app.Tab4.Title = '仪器设备';
                        app.Tab5.Title = '实验报告';
                        app.Tab3.Parent = [];
                        app.Tab9.Parent = app.TabGroup;
                        docType = '实验';
                    else
                        app.Tab4.Title = '实践教学';
                        app.Tab5.Title = '教学方法';   
                        docType = '理论';
                    end
                    app.Tab8.Parent = [];                    
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            homePath = cd;
            idx = regexp(homePath,'\');
            app.DefaultSavePath = homePath(1:idx(end-3));
            % 载入远程课程体系和指标点
            dbsFile = [app.DefaultSavePath,'database.mat'];
            if exist(dbsFile,'file') == 2
                app.Info.Value = SetLog(app,sprintf('载入"%s"中的指标点和课程体系',dbsFile));
                load(dbsFile,app.DsCurriculum,app.DsIndicators);
                app.Curriculum = eval(app.DsCurriculum);
                app.Indicators = eval(app.DsIndicators);
                dat = cell(height(app.Curriculum),3);
                for i = 1:length(dat)
                    dat{i,1} = app.Curriculum.Name{i};
                    dat{i,2} = false;
                    dat{i,3} = '';
                end
            else
                app.Info.Value = SetLog(app,sprintf('找不到文件："%s”',dbsFile));
                app.Download.Enable = false;
            end
            datFile = [app.DefaultSavePath,'app.mat'];
            if exist(datFile,'file') == 2
                app.Info.Value = SetLog(app,sprintf('载入远程文件%s',datFile));
                load(datFile,'cs')
                if exist('cs','var') == 1
                    app.Info.Value = SetLog(app,sprintf('载入%d门课程信息',length(cs)));
                    app.Name.Items = ["",cs.Title];
                    app.AllCourses = cs;
                    for i = 1:length(cs)
                        idx = strcmp(cs(i).Title,dat(:,1));
                        if sum(idx) == 1
                            dat{find(idx),2} = true;
                            dat{find(idx),3} = cs(i).LogInfo;
                        end
                    end
                end
            else
                app.Info.Value = SetLog(app,sprintf('找不到远程数据文件%s',datFile));
            end
            app.CurriculumList.Data = dat;

            % 版本信息
            info3 = DataHash(app.DsCurriculum);
            info2 = DataHash(app.DsIndicators);
            info1 = '修订版v1.5';
            app.Version.Text = strcat('（',info1,'@',...
                              '指标点',info2(end-3:end),...
                              '、支撑矩阵',info3(end-3:end),'）');
            app.mainUI.Name = strcat(app.mainUI.Name,app.Version.Text);
        end

        % Value changed function: Name
        function NameValueChanged(app, event)
            iCourse = strcmp(app.Name.Value,[app.AllCourses.Title]);
            app.CurrentCourse = app.AllCourses(find(iCourse,1,'last'));
            % 载入所选课程信息
            app.Info.Value = SetLog(app,sprintf('载入《%s》课程教纲信息，创建于%s！',...
                char(app.Name.Value),app.CurrentCourse.LogInfo));
            app.Code.Value = app.CurrentCourse.Code;
            app.Type.Value = app.CurrentCourse.Category;
            docType = updateTabs(app);
            if app.Type.Value == "选修课"
                app.CompulsoryOrNot.Value = "选修";
            else
                app.CompulsoryOrNot.Value = "必修";
            end
            app.Credit.Value = app.CurrentCourse.Credits;
            app.Hours.Value = app.CurrentCourse.ClassHour;
            app.Semester.Value = app.CurrentCourse.Semester;
            app.Language.Value = app.CurrentCourse.Language;
            if ~isempty(app.CurrentCourse.Prerequisites)
                app.DispPrerequisites.Value = app.CurrentCourse.Prerequisites;
            end
            % 列出该课程支撑的毕业要求指标点
            dat1 = app.CurrentCourse.Outcomes;
            dat1 = [dat1,app.CurrentCourse.Objectives];           
            app.TabReq2Obj.Data = dat1;
            app.Description.Value = app.CurrentCourse.Description;
            app.Content.Value = app.CurrentCourse.Content;
            app.ExpTeach.Value = app.CurrentCourse.TeachMethod;
            app.ExamMethod.Value = app.CurrentCourse.ExamMethod;
            app.Benchmark.Data = app.CurrentCourse.Benchmark;   
            app.Textbook.Value = app.CurrentCourse.Textbook;
            app.Notices.Value = app.CurrentCourse.Notices;
            app.ExpDetails.Data = app.CurrentCourse.ExpDetail;
            app.ExpNum.Value = size(app.CurrentCourse.ExpDetail,1);
            
            function listCourse = ListPrerequisites(course,curriculum)
                % 检查输入参数
                curriculum.Semester = double(curriculum.Semester);
                index = strcmp(course,curriculum.Name);
                if sum(index) == 1
                    indices = curriculum.Semester <= curriculum.Semester(index);
                    listCourse = curriculum.Name(indices);
                else
                    warning('未在课程体系中找到唯一的输入课程！')
                    listCourse = "N/A";
                end
            end
        end

        % Button pushed function: Download
        function DownloadPushed(app, event)
            % 输出教纲模板文件
            [file,path] = uiputfile(strcat(app.CurrentCourse.Title,'.docx'));
            if ~any([path,file]) % 当在文件选取窗中点"取消"终止函数执行
                return
            end
            app.Info.Value = sprintf('在“%s”生成文件“%s”',path,file);
            app.CurrentCourse.FilePath = strcat(path,file);
            flag = app.CurrentCourse.LogInfo; 
            cc = app.CurrentCourse;
            app.Info.Value = SetLog(app,Syllabus_genDoc(cc,flag));
%             app.delete
        end

        % Cell edit callback: TabReq2Obj
        function TabReq2ObjCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.CurrentCourse.Objectives{indices(1),1} = newData;
        end

        % Value changed function: Description
        function DescriptionValueChanged(app, event)
            value = app.Description.Value;
            app.CurrentCourse.Description = value;
        end

        % Value changed function: Content
        function ContentValueChanged(app, event)
            value = app.Content.Value;
            app.CurrentCourse.Content = value;
        end

        % Value changed function: ExpTeach
        function ExpTeachValueChanged(app, event)
            value = app.ExpTeach.Value;
            app.CurrentCourse.ExpTeach = value;
        end

        % Value changed function: TeachMethod
        function TeachMethodValueChanged(app, event)
            value = app.TeachMethod.Value;
            app.CurrentCourse.TeachMethod = value;
        end

        % Value changed function: ExamMethod
        function ExamMethodValueChanged(app, event)
            value = app.ExamMethod.Value;
            app.CurrentCourse.ExamMethod = value;
        end

        % Value changed function: Textbook
        function TextbookValueChanged(app, event)
            value = app.Textbook.Value;
            app.CurrentCourse.Textbook = value;
        end

        % Callback function
        function LoadDataPushed(app, event)
            % 该回调程序已废弃
            if app.LocalOrNot.Value
                [file,path] = uigetfile('app.mat','MultiSelect',"off");
                appFilePath = [path,file];
                if ~any([path,file]) % 当在文件选取窗中点"取消"终止函数执行
                    return
                end
            else
                appFilePath = [app.DefaultSavePath,'app.mat'];
            end
            load(appFilePath,'cc')
            app.CurrentCourse = cc;
            updateTabs(app)
            app.Code.Value = cc.Code;
            app.Type.Value = cc.Category;
            if cc.CompulsoryOrNot
                app.CompulsoryOrNot.Value = "必修";
            else
                app.CompulsoryOrNot.Value = "选修";
            end
            app.Hours.Value = cc.ClassHour;
            app.Credit.Value = cc.Credits;
            app.Semester.Value = cc.Credits;
            app.Language.Value = cc.Language;
            app.Name.Value = cc.Title;
            if ~isempty(cc.Prerequisites)
                app.DispPrerequisites.Value = cc.Prerequisites;     
            end
            app.TabReq2Obj.Data = horzcat(cc.Outcomes,cc.Objectives);
            app.TabReq2Obj.ColumnEditable = [false,true];
            app.Description.Value = cc.Description;
            app.Content.Value = cc.Content;
            app.ExpTeach.Value = cc.ExpTeach;
            app.TeachMethod.Value = cc.TeachMethod;
            app.ExamMethod.Value = cc.ExamMethod;
            app.Textbook.Value = cc.Textbook;
            %
            NObj = size(cc.Objectives,1);
            dat2 = cell(NObj,6);
            dat2(:,1) = cellstr(arrayfun(@(x)strcat('[o',x,']'),string((1:NObj)')));
            app.Benchmark.Data = dat2;
            if ~isempty(cc.Benchmark)
                app.Benchmark.Data(:,2:end) = cc.Benchmark(:,2:end);
                app.CurrentCourse.Benchmark = app.Benchmark.Data;
            end
            if ~isempty(cc.ExpDetail)
                app.ExpNum.Value = size(cc.ExpDetail,1);
                app.ExpDetails.Data = cc.ExpDetail;
            end
        end

        % Cell edit callback: Benchmark
        function BenchmarkCellEdit(app, event)
            app.CurrentCourse.Benchmark = app.Benchmark.Data;
            indices = event.Indices;
            newData = event.NewData;
            app.CurrentCourse.Benchmark{indices(1),indices(2)} = newData;
        end

        % Button pushed function: LocalSave
        function LocalSaveButtonPushed(app, event)
            [file,path] = uiputfile(sprintf('app《%s》教纲本地数据.mat',app.Name.Value));
            if ~any([path,file]) % 当在文件选取窗中点"取消"终止函数执行
                return
            end
            cc = app.CurrentCourse;
            cc.LogInfo = strcat(datestr(now),app.AppInfo);
            save(strcat(path,file),'cc');
        end

        % Value changed function: Notices
        function NoticesValueChanged(app, event)
            value = app.Notices.Value;
            app.CurrentCourse.Notices = value;
        end

        % Value changed function: ExpNum
        function ExpNumValueChanged(app, event)
            value = app.ExpNum.Value;
            tabExpDetails = app.ExpDetails.Data;
            rowN = size(tabExpDetails,1);
            if isempty(tabExpDetails)
                app.ExpDetails.Data = cell(value,8);
                app.ExpDetails.Data(:,1) = cellstr(string(1:value)');
                app.CurrentCourse.ExpDetail(:,1) = cellstr(string(1:value)');
            else
                if rowN > value
                    app.ExpDetails.Data(value+1:rowN,:) = [];
                elseif rowN < value
                    app.ExpDetails.Data(rowN+1:value,1) = cellstr(string(rowN+1:value)');
                    app.CurrentCourse.ExpDetail(rowN+1:value,1) = cellstr(string(rowN+1:value)');
                end
            end
        end

        % Cell edit callback: ExpDetails
        function ExpDetailsCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.CurrentCourse.ExpDetail{indices(1),indices(2)} = newData;
        end

        % Cell selection callback: CurriculumList
        function CurriculumListCellSelection(app, event)
            indices = event.Indices;
            if app.CurriculumList.Data{indices(1),2}
                app.Name.Value = app.CurriculumList.Data{indices(1),1};
                NameValueChanged(app,event);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mainUI = uifigure('Visible', 'off');
            app.mainUI.Name = 'Syllabus Admin';
            app.LabelHeadTitle = uilabel(app.mainUI);
            app.LabelHeadTitle.Text = '课程教学大纲数据管理器';
            app.Label_3 = uilabel(app.mainUI);
            app.Label_3.Text = '课程代码';
            app.Code = uieditfield(app.mainUI, 'text');
            app.Code.Editable = 'off';
            app.Label_4 = uilabel(app.mainUI);
            app.Label_4.Text = '课程类型';
            app.Type = uieditfield(app.mainUI, 'text');
            app.Type.Editable = 'off';
            app.Label_5 = uilabel(app.mainUI);
            app.Label_5.Text = '课程性质';
            app.CompulsoryOrNot = uieditfield(app.mainUI, 'text');
            app.CompulsoryOrNot.Editable = 'off';
            app.Label_6 = uilabel(app.mainUI);
            app.Label_6.Text = '课程时间';
            app.Hours = uieditfield(app.mainUI, 'text');
            app.Hours.Editable = 'off';
            app.Label_7 = uilabel(app.mainUI);
            app.Label_7.Text = '课程学分';
            app.Credit = uieditfield(app.mainUI, 'text');
            app.Credit.Editable = 'off';
            app.Label_8 = uilabel(app.mainUI);
            app.Label_8.Text = '开课学期';
            app.Semester = uieditfield(app.mainUI, 'text');
            app.Semester.Editable = 'off';
            app.Label_9 = uilabel(app.mainUI);
            app.Label_9.Text = '授课语言';
            app.Language = uieditfield(app.mainUI, 'text');
            app.Language.Editable = 'off';
            app.Label_11 = uilabel(app.mainUI);
            app.Label_11.Text = '课程名称';
            app.Name = uidropdown(app.mainUI);
            app.Name.ValueChangedFcn = createCallbackFcn(app, @NameValueChanged, true);
            app.Download = uibutton(app.mainUI, 'push');
            app.Download.ButtonPushedFcn = createCallbackFcn(app, @DownloadPushed, true);
            app.Download.Text = '生成教纲文档';
            app.TabGroup = uitabgroup(app.mainUI);
            app.Tab1 = uitab(app.TabGroup);
            app.Tab1.Title = '课程目标';
            app.TabReq2Obj = uitable(app.Tab1);
            app.TabReq2Obj.ColumnName = {'课程支撑的毕业要求'; '课程教学目标'};
            app.TabReq2Obj.RowName = {};
            app.TabReq2Obj.CellEditCallback = createCallbackFcn(app, @TabReq2ObjCellEdit, true);
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = '课程简介';
            app.Description = uitextarea(app.Tab2);
            app.Description.ValueChangedFcn = createCallbackFcn(app, @DescriptionValueChanged, true);
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = '教学内容';
            app.Content = uitextarea(app.Tab3);
            app.Content.ValueChangedFcn = createCallbackFcn(app, @ContentValueChanged, true);
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Title = '实践教学';
            app.ExpTeach = uitextarea(app.Tab4);
            app.ExpTeach.ValueChangedFcn = createCallbackFcn(app, @ExpTeachValueChanged, true);
            app.Tab5 = uitab(app.TabGroup);
            app.Tab5.Title = '教学方法';
            app.TeachMethod = uitextarea(app.Tab5);
            app.TeachMethod.ValueChangedFcn = createCallbackFcn(app, @TeachMethodValueChanged, true);
            app.Tab6 = uitab(app.TabGroup);
            app.Tab6.Title = '考核方式';
            app.ExamMethod = uitextarea(app.Tab6);
            app.ExamMethod.ValueChangedFcn = createCallbackFcn(app, @ExamMethodValueChanged, true);
            app.Benchmark = uitable(app.Tab6);
            app.Benchmark.ColumnName = {'#'; '优秀'; '良好'; '中等'; '合格'; '不合格'};
            app.Benchmark.RowName = {};
            app.Benchmark.ColumnEditable = [false true true true true true];
            app.Benchmark.CellEditCallback = createCallbackFcn(app, @BenchmarkCellEdit, true);
            app.Tab7 = uitab(app.TabGroup);
            app.Tab7.Title = '教材资料';
            app.Textbook = uitextarea(app.Tab7);
            app.Textbook.ValueChangedFcn = createCallbackFcn(app, @TextbookValueChanged, true);
            app.Tab8 = uitab(app.TabGroup);
            app.Tab8.Title = '注意事项';
            app.Notices = uitextarea(app.Tab8);
            app.Notices.ValueChangedFcn = createCallbackFcn(app, @NoticesValueChanged, true);
            app.Tab9 = uitab(app.TabGroup);
            app.Tab9.Title = '实验内容';
            app.ExpDetails = uitable(app.Tab9);
            app.ExpDetails.ColumnName = {'编号'; '实验项目名称'; '学时'; '实验内容提要'; '实验类型'; '实验要求'; '每组人数'; '主要仪器与软件'};
            app.ExpDetails.RowName = {};
            app.ExpDetails.ColumnEditable = true;
            app.ExpDetails.CellEditCallback = createCallbackFcn(app, @ExpDetailsCellEdit, true);
            app.Label_15 = uilabel(app.Tab9);
            app.Label_15.Text = '实验项目数量';
            app.ExpNum = uispinner(app.Tab9);
            app.ExpNum.Limits = [0 Inf];
            app.ExpNum.ValueDisplayFormat = '%.0f';
            app.ExpNum.ValueChangedFcn = createCallbackFcn(app, @ExpNumValueChanged, true);
            app.Image = uiimage(app.mainUI);
            app.Image.ImageSource = 'bedge_small.png';
            app.Version = uilabel(app.mainUI);
            app.LocalSave = uibutton(app.mainUI, 'push');
            app.LocalSave.ButtonPushedFcn = createCallbackFcn(app, @LocalSaveButtonPushed, true);
            app.LocalSave.Text = '保存大纲数据';
            app.Copyright = uilabel(app.mainUI);
            app.Copyright.Text = 'Copyright © 2021-2022 SCUT.EChE.PII. All rights reserved.';
            app.Info = uitextarea(app.mainUI);
            app.Info.Editable = 'off';
            app.Label_16 = uilabel(app.mainUI);
            app.Label_16.Text = '先修课程';
            app.DispPrerequisites = uitextarea(app.mainUI);
            app.CurriculumList = uitable(app.mainUI);
            app.CurriculumList.ColumnName = {'课程名称'; '是否提交教纲'; '备注'};
            app.CurriculumList.RowName = {};
            app.CurriculumList.CellSelectionCallback = createCallbackFcn(app, @CurriculumListCellSelection, true);
            % Show the figure after all components are created
            app.mainUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Syllabus_Admin_UI_main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mainUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mainUI)
        end
    end
end