classdef Syllabus_genDoc_UI_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SyllabusAssistantUIFigure  matlab.ui.Figure
        LabelHeadTitle             matlab.ui.control.Label
        Label_3                    matlab.ui.control.Label
        Code                       matlab.ui.control.EditField
        Label_4                    matlab.ui.control.Label
        Type                       matlab.ui.control.EditField
        Label_5                    matlab.ui.control.Label
        CompulsoryOrNot            matlab.ui.control.EditField
        Label_6                    matlab.ui.control.Label
        Hours                      matlab.ui.control.EditField
        Label_7                    matlab.ui.control.Label
        Credit                     matlab.ui.control.EditField
        Label_8                    matlab.ui.control.Label
        Semester                   matlab.ui.control.EditField
        Label_9                    matlab.ui.control.Label
        Language                   matlab.ui.control.EditField
        Label_10                   matlab.ui.control.Label
        SelectPrerequisites        matlab.ui.control.ListBox
        Label_11                   matlab.ui.control.Label
        Name                       matlab.ui.control.DropDown
        SaveData                   matlab.ui.control.Button
        DispPrerequisites          matlab.ui.control.TextArea
        TabGroup                   matlab.ui.container.TabGroup
        Tab1                       matlab.ui.container.Tab
        TabReq2Obj                 matlab.ui.control.Table
        Tab2                       matlab.ui.container.Tab
        Description                matlab.ui.control.TextArea
        Tab3                       matlab.ui.container.Tab
        Content                    matlab.ui.control.TextArea
        Tab4                       matlab.ui.container.Tab
        ExpTeach                   matlab.ui.control.TextArea
        Tab5                       matlab.ui.container.Tab
        TeachMethod                matlab.ui.control.TextArea
        Tab6                       matlab.ui.container.Tab
        ExamMethod                 matlab.ui.control.TextArea
        Benchmark                  matlab.ui.control.Table
        Tab7                       matlab.ui.container.Tab
        Textbook                   matlab.ui.control.TextArea
        Tab8                       matlab.ui.container.Tab
        Notices                    matlab.ui.control.TextArea
        Tab9                       matlab.ui.container.Tab
        ExpDetails                 matlab.ui.control.Table
        Label_15                   matlab.ui.control.Label
        ExpNum                     matlab.ui.control.Spinner
        LoadData                   matlab.ui.control.Button
        Image                      matlab.ui.control.Image
        Label_13                   matlab.ui.control.Label
        Info                       matlab.ui.control.EditField
        Version                    matlab.ui.control.Label
        Label_14                   matlab.ui.control.Label
        BufferInfo                 matlab.ui.control.EditField
        LocalOrNot                 matlab.ui.control.StateButton
        LocalSave                  matlab.ui.control.Button
        Copyright                  matlab.ui.control.Label
        Label_16                   matlab.ui.control.Label
        ReleaseYear                matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        DsCurriculum = 'db_Curriculum2021'; % 课程体系描述字段
        DsIndicators = 'db_Indicators2021'; % 毕业要求指标点（内涵观测点）
        Curriculum;
        Indicators;
        DefaultSavePath = '';
        CurrentCourse Course
        SavedCourses Course % 从app.mat中载入cs
        AppInfo;
        Logger log4m
    end
    
    methods (Access = private)
        
        function checkBufferInfo(app)
            appFilePath = [app.DefaultSavePath,'app.mat'];
            if exist(appFilePath,'file') ~= 2
                app.Info.Value = sprintf('找不到文件："%s”',appFilePath);
                app.LoadData.Enable = false;
                app.BufferInfo.Enable = false;
            else
                load(appFilePath,'cc')
                app.BufferInfo.Value = strcat(cc.Title,cc.LogInfo);
            end
        end
        
        function docType = updateTabs(app)
            app.Tab3.Parent = app.TabGroup;
            app.Tab9.Parent = [];
            switch app.CurrentCourse.Category
                case('集中实践教学')
                    app.Tab4.Title = '实习方式';
                    app.Tab5.Title = '实习地点';                    
                    app.Tab8.Parent = app.TabGroup;
                    docType = '实习';
                otherwise
                    if ~isempty(regexp(app.CurrentCourse.Title,'实验','once'))
                        app.Tab4.Title = '仪器设备';
                        app.Tab5.Title = '实验报告';
                        app.Tab3.Parent = [];
                        app.Tab9.Parent = app.TabGroup;
                        docType = '实验';
                    else
                        app.Tab4.Title = '实践教学';
                        app.Tab5.Title = '教学方法';   
                        docType = '理论';
                    end
                    app.Tab8.Parent = [];                    
            end
        end
        
        function FillData(app,cc)
            app.CurrentCourse = cc;
            updateTabs(app);
            if cc.ReleaseYear ~= ""
                app.ReleaseYear.Value = char(cc.ReleaseYear);
            end
            app.Code.Value = cc.Code;
            app.Type.Value = cc.Category;
            if cc.CompulsoryOrNot
                app.CompulsoryOrNot.Value = "必修";
            else
                app.CompulsoryOrNot.Value = "选修";
            end
            app.Hours.Value = cc.ClassHour;
            app.Credit.Value = cc.Credits;
            app.Semester.Value = cc.Semester;
            app.Language.Value = cc.Language;
            app.Name.Value = cc.Title;
            if ~isempty(cc.Prerequisites)
                app.DispPrerequisites.Value = cc.Prerequisites;
            end
            if isempty(cc.Objectives) || any(strcmp('暂缺',cc.Objectives))
                cc.Objectives = cell(size(cc.Outcomes));
                cc.Objectives{1} = '暂缺';
            end
            app.TabReq2Obj.Data = horzcat(cc.Outcomes,cc.Objectives);
            app.TabReq2Obj.ColumnEditable = [false,true];
            app.Description.Value = cc.Description;
            app.Content.Value = cc.Content;
            app.ExpTeach.Value = cc.ExpTeach;
            app.TeachMethod.Value = cc.TeachMethod;
            app.ExamMethod.Value = cc.ExamMethod;
            app.Textbook.Value = cc.Textbook;
            %
            NObj = size(cc.Objectives,1);
            if NObj == 0
                NObj = 1;
            end
            dat2 = cell(NObj,6);
            dat2(:,1) = cellstr(arrayfun(@(x)strcat('[o',x,']'),string((1:NObj)')));
            app.Benchmark.Data = dat2;
            if ~isempty(cc.Benchmark)
                app.Benchmark.Data(:,2:end) = cc.Benchmark(:,2:end);
                app.CurrentCourse.Benchmark = app.Benchmark.Data;
            end
            if ~isempty(cc.ExpDetail)
                app.ExpNum.Value = size(cc.ExpDetail,1);
                app.ExpDetails.Data = cc.ExpDetail;
            end
        end

        function Initiation(app)
            dbsFilePath = [app.DefaultSavePath,'database.mat'];
            if exist(dbsFilePath,'file') == 2 
                info = sprintf('载入%s"中的指标点和课程体系',dbsFilePath);
                app.Info.Value = info;
                app.Logger.info('Initiation',info)
                load(dbsFilePath,app.DsCurriculum,app.DsIndicators);
                app.Curriculum = eval(app.DsCurriculum);
                app.Indicators = eval(app.DsIndicators);
                app.Name.Items = app.Curriculum.Name;
                app.SelectPrerequisites.Items = app.Curriculum.Name;
            else
                info = sprintf('找不到文件：“%s”',dbsFilePath);
                app.Info.Value = info;
                app.Logger.info('Initiation',info)
                app.SaveData.Enable = false;
            end
            % 从app.mat载入已录入教纲的全部课程
            appFilePath = [app.DefaultSavePath,'app.mat'];
            if exist(appFilePath,'file') == 2
                load(appFilePath,'cs')
                idx = strcmp([cs.ReleaseYear],app.ReleaseYear.Value);
                app.SavedCourses = cs(idx);
                info = sprintf('载入%s年度培养方案的%d门已录入教纲的课程数据',app.ReleaseYear.Value,sum(idx));
                app.Info.Value = info;
                app.Logger.info('Initiation',info)
            end
            checkBufferInfo(app) % 远程数据提示信息
            % 版本信息
            info3 = DataHash(dbsFilePath,'file');
            info2 = DataHash(appFilePath,'file');
            info1 = '修订版v4.1';
            app.AppInfo = strcat('（',info1,'@',...
                              '教纲数据',info2(end-3:end),...
                              '、培养方案',info3(end-3:end),'）');
            app.Version.Text = app.AppInfo;
            app.Logger.info('Initiation',app.AppInfo)
            app.LocalOrNot.Value = false;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            homePath = cd;
            app.Logger = log4m.getLogger;
            if contains(homePath,'\Git\EnergyChE')
                app.DefaultSavePath = [homePath,'\'];
                app.Logger.info('startupFcn','本地调试模式')
            else
                idx = regexp(homePath,'\');
                app.DefaultSavePath = homePath(1:idx(end-3));
                app.Logger.info('startupFcn','webapp模式')
            end
            Initiation(app) % 按缺省值初始化
        end

        % Value changed function: Name
        function NameValueChanged(app, event)
            savedCourseList = arrayfun(@(x)x.Title,app.SavedCourses);
            idx = strcmp(app.Name.Value,savedCourseList);
            if any(idx)
                info = sprintf('载入已录入的最新《%s》教纲数据',app.Name.Value);
                app.Info.Value = info;
                app.Logger.info('NameValueChanged',info)
                iCourse = find(idx,1,'last');
                cc = app.SavedCourses(iCourse);
                app.CurrentCourse = cc;
                FillData(app,cc)
            else
                info = sprintf('未找到现有《%s》教纲数据',app.Name.Value);
                app.Info.Value = info;
                app.Logger.info('startupFcn',info)
                cc = Course(app.Name.Value);
                FillData(app,cc) % 先将课程信息置空
                app.CurrentCourse.ReleaseYear = string(app.ReleaseYear.Value);
                iCourse = strcmp(app.Name.Value,app.Curriculum.Name);
                app.Code.Value = app.Curriculum.IDv2018(iCourse);
                app.CurrentCourse.Code = app.Code.Value;
                app.Type.Value = app.Curriculum.Type(iCourse);
                app.CurrentCourse.Category = app.Type.Value;
                docType = updateTabs(app);
                if app.Type.Value == "选修课"
                    app.CompulsoryOrNot.Value = "选修";
                    app.CurrentCourse.CompulsoryOrNot = false;
                else
                    app.CompulsoryOrNot.Value = "必修";
                    app.CurrentCourse.CompulsoryOrNot = true;                
                end
                app.Credit.Value = string(app.Curriculum.Credit(iCourse));
                app.CurrentCourse.Credits = app.Credit.Value;
                switch docType
                    case('理论')
                        app.Hours.Value = strcat(string(double(app.Curriculum.Credit(iCourse))*16),"学时");
                    case('实验')
                        app.Hours.Value = strcat(string(double(app.Curriculum.Credit(iCourse))*32),"学时");
                    case('实习')
                        app.Hours.Value = strcat(string(double(app.Curriculum.Credit(iCourse))),"周");
                end
                app.CurrentCourse.ClassHour = app.Hours.Value;
                app.Semester.Value = app.Curriculum.Semester(iCourse);
                app.CurrentCourse.Semester = app.Semester.Value;
                app.Language.Value = "中文";
                app.CurrentCourse.Language = app.Language.Value;
                app.SelectPrerequisites.Items = ListPrerequisites(app.Name.Value,app.Curriculum);
                % 列出该课程支撑的毕业要求指标点
                app.TabReq2Obj.ColumnEditable = [false true];
                idx = logical(app.Curriculum.ReqMatrix(iCourse,:));
                indicators = app.Indicators{idx,:};
                dat1 = cell(size(indicators,1),2);
                for i = 1:size(indicators,1)
                    dat1{i,1} = sprintf('%s %s',indicators{i,1},indicators{i,2});
                end
                app.TabReq2Obj.Data = dat1;
                app.CurrentCourse.Outcomes = dat1(:,1);
                dat2 = cell(size(indicators,1),6);
                dat2(:,1) = cellstr(arrayfun(@(x)strcat('[o',x,']'),string((1:size(indicators,1))')));
                app.Benchmark.Data = dat2;
            end
            
            function listCourse = ListPrerequisites(course,curriculum)
                % 检查输入参数
                curriculum.Semester = double(curriculum.Semester);
                index = strcmp(course,curriculum.Name);
                if sum(index) == 1
                    indices = curriculum.Semester <= curriculum.Semester(index);
                    listCourse = curriculum.Name(indices);
                else
                    warning('未在课程体系中找到唯一的输入课程！')
                    listCourse = "N/A";
                end
            end
        end

        % Button pushed function: SaveData
        function SaveDataPushed(app, event)
            % 输出教纲模板文件
            [file,path] = uiputfile(strcat(app.CurrentCourse.Title,'.docx'));
            if ~any([path,file]) % 当在文件选取窗中点"取消"终止函数执行
                return
            end
            info1 = sprintf('在"%s"生成文件"%s”',path,file);
            app.CurrentCourse.ReleaseYear = string(app.ReleaseYear.Value);
            app.Info.Value = info1;
            app.Logger.info('SaveDataPushed',info1)
            app.CurrentCourse.FilePath = strcat(path,file);
            flag = strcat(datestr(now),app.AppInfo);
            app.CurrentCourse.LogInfo = flag;
            cc = app.CurrentCourse;
            hash_cc = DataHash(cc); hash_cc = hash_cc(end-3:end);
            flag = sprintf('%s；教纲哈希码%s',flag,hash_cc);
            app.Info.Value = Syllabus_genDoc(cc,flag);
            % 保存数据
            appFilePath = [app.DefaultSavePath,'app.mat'];
            if exist(appFilePath,'file') == 2
                load(appFilePath,'cs')
                if exist('cs','var') == 1
                    cs = [cs,cc];
                else
                    cs = cc;
                end
            else
                cs = cc;
            end
            save(appFilePath,'cc','cs')
            hash_appmat = DataHash(appFilePath,'file'); 
            hash_appmat = hash_appmat(end-3:end);
            info2 = sprintf('保存《%s》教纲数据（%s）于app.mat（%s）',...
                cc.Title,hash_cc,hash_appmat);
            app.Info.Value = info2;
            app.Logger.info('SaveDataPushed',info2)            
%             app.delete
        end

        % Value changed function: SelectPrerequisites
        function SelectPrerequisitesValueChanged(app, event)
            value = app.SelectPrerequisites.Value;
            if ismissing(app.DispPrerequisites.Value)
                app.DispPrerequisites.Value = value;
            else
                idx = strcmp(value,app.DispPrerequisites.Value);
                if any(idx)
                    app.DispPrerequisites.Value(idx) = [];
                else
                    app.DispPrerequisites.Value = vertcat(app.DispPrerequisites.Value,value);
                end               
            end
            app.CurrentCourse.Prerequisites = app.DispPrerequisites.Value;
        end

        % Callback function
        function SelectFileValueChanged(app, event)
            [~,app.TemplatePath] = uigetfile('syllabus.dotx');
            if ischar(app.DefaultSavePath)
                app.SaveFilePath.Value = app.TemplatePath;
                app.SaveData.Enable = true;
            end
        end

        % Callback function
        function SaveFilePathValueChanged(app, event)
            app.DefaultSavePath = app.SaveFilePath.Value;
            app.SaveData.Enable = true;
        end

        % Cell edit callback: TabReq2Obj
        function TabReq2ObjCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.CurrentCourse.Objectives{indices(1),1} = newData;
        end

        % Value changed function: Description
        function DescriptionValueChanged(app, event)
            value = app.Description.Value;
            app.CurrentCourse.Description = value;
        end

        % Value changed function: Content
        function ContentValueChanged(app, event)
            value = app.Content.Value;
            app.CurrentCourse.Content = value;
        end

        % Value changed function: ExpTeach
        function ExpTeachValueChanged(app, event)
            value = app.ExpTeach.Value;
            app.CurrentCourse.ExpTeach = value;
        end

        % Value changed function: TeachMethod
        function TeachMethodValueChanged(app, event)
            value = app.TeachMethod.Value;
            app.CurrentCourse.TeachMethod = value;
        end

        % Value changed function: ExamMethod
        function ExamMethodValueChanged(app, event)
            value = app.ExamMethod.Value;
            app.CurrentCourse.ExamMethod = value;
        end

        % Value changed function: Textbook
        function TextbookValueChanged(app, event)
            value = app.Textbook.Value;
            app.CurrentCourse.Textbook = value;
        end

        % Button pushed function: LoadData
        function LoadDataPushed(app, event)
            if app.LocalOrNot.Value
                [file,path] = uigetfile('app.mat','MultiSelect',"off");
                appFilePath = [path,file];
                if ~any([path,file]) % 当在文件选取窗中点"取消"终止函数执行
                    return
                end
            else
                appFilePath = [app.DefaultSavePath,'app.mat'];
            end
            load(appFilePath,'cc')
            FillData(app,cc);
        end

        % Cell edit callback: Benchmark
        function BenchmarkCellEdit(app, event)
            app.CurrentCourse.Benchmark = app.Benchmark.Data;
            indices = event.Indices;
            newData = event.NewData;
            app.CurrentCourse.Benchmark{indices(1),indices(2)} = newData;
        end

        % Value changed function: LocalOrNot
        function LocalOrNotValueChanged(app, event)
            value = app.LocalOrNot.Value;
            if value
                app.LocalOrNot.Text = '本地';
                app.LoadData.Enable = true;
                app.BufferInfo.Enable = false;
            else
                app.LocalOrNot.Text = '远程';
                app.BufferInfo.Enable = true;
                checkBufferInfo(app)
            end
        end

        % Button pushed function: LocalSave
        function LocalSaveButtonPushed(app, event)
            [file,path] = uiputfile('app.mat');
            if ~any([path,file]) % 当在文件选取窗中点"取消"终止函数执行
                return
            end
            app.CurrentCourse.ReleaseYear = string(app.ReleaseYear.Value);
            cc = app.CurrentCourse;
            cc.LogInfo = strcat(datestr(now),app.AppInfo);
            save(strcat(path,file),'cc');
        end

        % Value changed function: Notices
        function NoticesValueChanged(app, event)
            value = app.Notices.Value;
            app.CurrentCourse.Notices = value;
        end

        % Value changed function: ExpNum
        function ExpNumValueChanged(app, event)
            value = app.ExpNum.Value;
            tabExpDetails = app.ExpDetails.Data;
            rowN = size(tabExpDetails,1);
            if isempty(tabExpDetails)
                app.ExpDetails.Data = cell(value,8);
                app.ExpDetails.Data(:,1) = cellstr(string(1:value)');
                app.CurrentCourse.ExpDetail(:,1) = cellstr(string(1:value)');
            else
                if rowN > value
                    app.ExpDetails.Data(value+1:rowN,:) = [];
                elseif rowN < value
                    app.ExpDetails.Data(rowN+1:value,1) = cellstr(string(rowN+1:value)');
                    app.CurrentCourse.ExpDetail(rowN+1:value,1) = cellstr(string(rowN+1:value)');
                end
            end
        end

        % Cell edit callback: ExpDetails
        function ExpDetailsCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.CurrentCourse.ExpDetail{indices(1),indices(2)} = newData;
        end

        % Close request function: SyllabusAssistantUIFigure
        function SyllabusAssistantUIFigureCloseRequest(app, event)
            app.Logger.info('close','关闭SyllabusAssistant')
            delete(app)
        end

        % Value changed function: ReleaseYear
        function ReleaseYearValueChanged(app, event)
            value = app.ReleaseYear.Value;
            app.DsCurriculum = sprintf('db_Curriculum%s',value);
            app.DsIndicators = sprintf('db_Indicators%s',value);
            Initiation(app) % 重新载入培养方案数据
            if isequal(value,'2019') % 将2019培养方案课程列表中的TypeID改为Type
                c2019 = app.Curriculum;
                c2019.TypeID = arrayfun(@(x)ID2Type(x),c2019.TypeID);
                c2019.Properties.VariableNames("TypeID") = "Type";
                app.Curriculum = c2019;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SyllabusAssistantUIFigure = uifigure('Visible', 'off');
            app.SyllabusAssistantUIFigure.Name = 'Syllabus Assistant';
            app.SyllabusAssistantUIFigure.CloseRequestFcn = createCallbackFcn(app, @SyllabusAssistantUIFigureCloseRequest, true);
            app.LabelHeadTitle = uilabel(app.SyllabusAssistantUIFigure);
            app.LabelHeadTitle.Text = '课程教学大纲文档生成器';
            app.Label_3 = uilabel(app.SyllabusAssistantUIFigure);
            app.Label_3.Text = '课程代码';
            app.Code = uieditfield(app.SyllabusAssistantUIFigure, 'text');
            app.Code.Editable = 'off';
            app.Label_4 = uilabel(app.SyllabusAssistantUIFigure);
            app.Label_4.Text = '课程类型';
            app.Type = uieditfield(app.SyllabusAssistantUIFigure, 'text');
            app.Type.Editable = 'off';
            app.Label_5 = uilabel(app.SyllabusAssistantUIFigure);
            app.Label_5.Text = '课程性质';
            app.CompulsoryOrNot = uieditfield(app.SyllabusAssistantUIFigure, 'text');
            app.CompulsoryOrNot.Editable = 'off';
            app.Label_6 = uilabel(app.SyllabusAssistantUIFigure);
            app.Label_6.Text = '课程时间';
            app.Hours = uieditfield(app.SyllabusAssistantUIFigure, 'text');
            app.Hours.Editable = 'off';
            app.Label_7 = uilabel(app.SyllabusAssistantUIFigure);
            app.Label_7.Text = '课程学分';
            app.Credit = uieditfield(app.SyllabusAssistantUIFigure, 'text');
            app.Credit.Editable = 'off';
            app.Label_8 = uilabel(app.SyllabusAssistantUIFigure);
            app.Label_8.Text = '开课学期';
            app.Semester = uieditfield(app.SyllabusAssistantUIFigure, 'text');
            app.Semester.Editable = 'off';
            app.Label_9 = uilabel(app.SyllabusAssistantUIFigure);
            app.Label_9.Text = '授课语言';
            app.Language = uieditfield(app.SyllabusAssistantUIFigure, 'text');
            app.Language.Editable = 'off';
            app.Label_10 = uilabel(app.SyllabusAssistantUIFigure);
            app.Label_10.Text = '先修课程';
            app.SelectPrerequisites = uilistbox(app.SyllabusAssistantUIFigure);
            app.SelectPrerequisites.Multiselect = 'on';
            app.SelectPrerequisites.ValueChangedFcn = createCallbackFcn(app, @SelectPrerequisitesValueChanged, true);
            app.SelectPrerequisites.Tooltip = {'点选多门先修课程并列于右表；若该课程已列于右表，则删除该课程。'};
            app.SelectPrerequisites.Value = {'Item 1'};
            app.Label_11 = uilabel(app.SyllabusAssistantUIFigure);
            app.Label_11.Text = '课程名称';
            app.Name = uidropdown(app.SyllabusAssistantUIFigure);
            app.Name.ValueChangedFcn = createCallbackFcn(app, @NameValueChanged, true);
            app.SaveData = uibutton(app.SyllabusAssistantUIFigure, 'push');
            app.SaveData.ButtonPushedFcn = createCallbackFcn(app, @SaveDataPushed, true);
            app.SaveData.Text = '生成Word模板';
            app.DispPrerequisites = uitextarea(app.SyllabusAssistantUIFigure);
            app.DispPrerequisites.Editable = 'off';
            app.TabGroup = uitabgroup(app.SyllabusAssistantUIFigure);
            app.Tab1 = uitab(app.TabGroup);
            app.Tab1.Title = '课程目标';
            app.TabReq2Obj = uitable(app.Tab1);
            app.TabReq2Obj.ColumnName = {'课程支撑的毕业要求'; '课程教学目标'};
            app.TabReq2Obj.RowName = {};
            app.TabReq2Obj.CellEditCallback = createCallbackFcn(app, @TabReq2ObjCellEdit, true);
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = '课程简介';
            app.Description = uitextarea(app.Tab2);
            app.Description.ValueChangedFcn = createCallbackFcn(app, @DescriptionValueChanged, true);
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = '教学内容';
            app.Content = uitextarea(app.Tab3);
            app.Content.ValueChangedFcn = createCallbackFcn(app, @ContentValueChanged, true);
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Title = '实践教学';
            app.ExpTeach = uitextarea(app.Tab4);
            app.ExpTeach.ValueChangedFcn = createCallbackFcn(app, @ExpTeachValueChanged, true);
            app.Tab5 = uitab(app.TabGroup);
            app.Tab5.Title = '教学方法';
            app.TeachMethod = uitextarea(app.Tab5);
            app.TeachMethod.ValueChangedFcn = createCallbackFcn(app, @TeachMethodValueChanged, true);
            app.Tab6 = uitab(app.TabGroup);
            app.Tab6.Title = '考核方式';
            app.ExamMethod = uitextarea(app.Tab6);
            app.ExamMethod.ValueChangedFcn = createCallbackFcn(app, @ExamMethodValueChanged, true);
            app.Benchmark = uitable(app.Tab6);
            app.Benchmark.ColumnName = {'#'; '优秀'; '良好'; '中等'; '合格'; '不合格'};
            app.Benchmark.RowName = {};
            app.Benchmark.ColumnEditable = [false true true true true true];
            app.Benchmark.CellEditCallback = createCallbackFcn(app, @BenchmarkCellEdit, true);
            app.Tab7 = uitab(app.TabGroup);
            app.Tab7.Title = '教材资料';
            app.Textbook = uitextarea(app.Tab7);
            app.Textbook.ValueChangedFcn = createCallbackFcn(app, @TextbookValueChanged, true);
            app.Tab8 = uitab(app.TabGroup);
            app.Tab8.Title = '注意事项';
            app.Notices = uitextarea(app.Tab8);
            app.Notices.ValueChangedFcn = createCallbackFcn(app, @NoticesValueChanged, true);
            app.Tab9 = uitab(app.TabGroup);
            app.Tab9.Title = '实验内容';
            app.ExpDetails = uitable(app.Tab9);
            app.ExpDetails.ColumnName = {'编号'; '实验项目名称'; '学时'; '实验内容提要'; '实验类型'; '实验要求'; '每组人数'; '主要仪器与软件'};
            app.ExpDetails.RowName = {};
            app.ExpDetails.ColumnEditable = true;
            app.ExpDetails.CellEditCallback = createCallbackFcn(app, @ExpDetailsCellEdit, true);
            app.Label_15 = uilabel(app.Tab9);
            app.Label_15.Text = '实验项目数量';
            app.ExpNum = uispinner(app.Tab9);
            app.ExpNum.Limits = [0 Inf];
            app.ExpNum.ValueDisplayFormat = '%.0f';
            app.ExpNum.ValueChangedFcn = createCallbackFcn(app, @ExpNumValueChanged, true);
            app.LoadData = uibutton(app.SyllabusAssistantUIFigure, 'push');
            app.LoadData.ButtonPushedFcn = createCallbackFcn(app, @LoadDataPushed, true);
            app.LoadData.Text = '载入课程数据';
            app.Image = uiimage(app.SyllabusAssistantUIFigure);
            app.Image.ImageSource = 'bedge_small.png';
            app.Label_13 = uilabel(app.SyllabusAssistantUIFigure);
            app.Label_13.Text = '文件生成信息';
            app.Info = uieditfield(app.SyllabusAssistantUIFigure, 'text');
            app.Version = uilabel(app.SyllabusAssistantUIFigure);
            app.Label_14 = uilabel(app.SyllabusAssistantUIFigure);
            app.Label_14.Text = '远程缓存信息';
            app.BufferInfo = uieditfield(app.SyllabusAssistantUIFigure, 'text');
            app.LocalOrNot = uibutton(app.SyllabusAssistantUIFigure, 'state');
            app.LocalOrNot.ValueChangedFcn = createCallbackFcn(app, @LocalOrNotValueChanged, true);
            app.LocalOrNot.Text = '远程';
            app.LocalSave = uibutton(app.SyllabusAssistantUIFigure, 'push');
            app.LocalSave.ButtonPushedFcn = createCallbackFcn(app, @LocalSaveButtonPushed, true);
            app.LocalSave.Text = '保存本地数据';
            app.Copyright = uilabel(app.SyllabusAssistantUIFigure);
            app.Copyright.Text = 'Copyright © 2021-2022 SCUT.EChE.PII. All rights reserved.';
            app.Label_16 = uilabel(app.SyllabusAssistantUIFigure);
            app.Label_16.Text = '方案年份';
            app.ReleaseYear = uidropdown(app.SyllabusAssistantUIFigure);
            app.ReleaseYear.Items = {'2019', '2021'};
            app.ReleaseYear.ValueChangedFcn = createCallbackFcn(app, @ReleaseYearValueChanged, true);
            app.ReleaseYear.Value = '2021';
            % Show the figure after all components are created
            app.SyllabusAssistantUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Syllabus_genDoc_UI_main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SyllabusAssistantUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SyllabusAssistantUIFigure)
        end
    end
end